classdef CCEP_file < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout3                matlab.ui.container.GridLayout
        UPDATEWINDOWPanel          matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        UpdateWindow               matlab.ui.control.TextArea
        SubjectFileLabel           matlab.ui.control.Label
        TabGroup                   matlab.ui.container.TabGroup
        ManualInspectionTab        matlab.ui.container.Tab
        MI_grid                    matlab.ui.container.GridLayout
        MI_ChAx1                   matlab.ui.control.UIAxes
        MI_Ch1                     matlab.ui.control.StateButton
        MI_ChAx2                   matlab.ui.control.UIAxes
        MI_Ch2                     matlab.ui.control.StateButton
        MI_ChAx3                   matlab.ui.control.UIAxes
        MI_Ch3                     matlab.ui.control.StateButton
        MI_ChAx4                   matlab.ui.control.UIAxes
        MI_Ch4                     matlab.ui.control.StateButton
        MI_ChAx5                   matlab.ui.control.UIAxes
        MI_Ch5                     matlab.ui.control.StateButton
        MI_ChAx6                   matlab.ui.control.UIAxes
        MI_Ch6                     matlab.ui.control.StateButton
        MI_ChAx7                   matlab.ui.control.UIAxes
        MI_Ch7                     matlab.ui.control.StateButton
        MI_ChAx8                   matlab.ui.control.UIAxes
        MI_Ch8                     matlab.ui.control.StateButton
        MI_ChAx9                   matlab.ui.control.UIAxes
        MI_Ch9                     matlab.ui.control.StateButton
        MI_ChAx10                  matlab.ui.control.UIAxes
        MI_Ch10                    matlab.ui.control.StateButton
        MI_ChAx11                  matlab.ui.control.UIAxes
        MI_Ch11                    matlab.ui.control.StateButton
        MI_ChAx12                  matlab.ui.control.UIAxes
        MI_Ch12                    matlab.ui.control.StateButton
        MI_ChAx13                  matlab.ui.control.UIAxes
        MI_Ch13                    matlab.ui.control.StateButton
        MI_ChAx14                  matlab.ui.control.UIAxes
        MI_Ch14                    matlab.ui.control.StateButton
        MI_ChAx15                  matlab.ui.control.UIAxes
        MI_Ch15                    matlab.ui.control.StateButton
        MI_BadCh                   matlab.ui.control.Label
        MI_Left                    matlab.ui.control.Button
        MI_Switch                  matlab.ui.control.Switch
        MI_Right                   matlab.ui.control.Button
        MI_Confirm                 matlab.ui.control.Button
        NoiseToleranceTab          matlab.ui.container.Tab
        NT_grid                    matlab.ui.container.GridLayout
        NT_LF_Panel                matlab.ui.container.Panel
        NT_LFgrid                  matlab.ui.container.GridLayout
        NT_HP                      matlab.ui.control.CheckBox
        withsignificantinterceptGaugeLabel  matlab.ui.control.Label
        NT_InterceptGauge          matlab.ui.control.LinearGauge
        withsignificantslopeLabel  matlab.ui.control.Label
        NT_SlopeGauge              matlab.ui.control.LinearGauge
        NT_LineNoise_Panel         matlab.ui.container.Panel
        NT_LineGrid                matlab.ui.container.GridLayout
        NT_AxLineNoise             matlab.ui.control.UIAxes
        NT_Notch60                 matlab.ui.control.CheckBox
        NT_Notch120                matlab.ui.control.CheckBox
        NT_Notch180                matlab.ui.control.CheckBox
        NT_HF_Panel                matlab.ui.container.Panel
        NT_HFgrid                  matlab.ui.container.GridLayout
        NT_LP                      matlab.ui.control.CheckBox
        NT_AxHFrms                 matlab.ui.control.UIAxes
        NT_AxHFsum                 matlab.ui.control.UIAxes
        NT_AxHF                    matlab.ui.control.UIAxes
        NT_Confirm                 matlab.ui.control.Button
        ArtifactRemovalTab         matlab.ui.container.Tab
        AR_grid                    matlab.ui.container.GridLayout
        AR_ChAx1                   matlab.ui.control.UIAxes
        AR_Ch1                     matlab.ui.control.StateButton
        AR_ChAx2                   matlab.ui.control.UIAxes
        AR_Ch2                     matlab.ui.control.StateButton
        AR_ChAx3                   matlab.ui.control.UIAxes
        AR_Ch3                     matlab.ui.control.StateButton
        AR_ChAx4                   matlab.ui.control.UIAxes
        AR_Ch4                     matlab.ui.control.StateButton
        AR_ChAx5                   matlab.ui.control.UIAxes
        AR_Ch5                     matlab.ui.control.StateButton
        AR_ChAx6                   matlab.ui.control.UIAxes
        AR_Ch6                     matlab.ui.control.StateButton
        AR_ChAx7                   matlab.ui.control.UIAxes
        AR_Ch7                     matlab.ui.control.StateButton
        AR_ChAx8                   matlab.ui.control.UIAxes
        AR_Ch8                     matlab.ui.control.StateButton
        AR_ChAx9                   matlab.ui.control.UIAxes
        AR_Ch9                     matlab.ui.control.StateButton
        AR_ChAx10                  matlab.ui.control.UIAxes
        AR_Ch10                    matlab.ui.control.StateButton
        AR_ChAx11                  matlab.ui.control.UIAxes
        AR_Ch11                    matlab.ui.control.StateButton
        AR_ChAx12                  matlab.ui.control.UIAxes
        AR_Ch12                    matlab.ui.control.StateButton
        AR_ChAx13                  matlab.ui.control.UIAxes
        AR_Ch13                    matlab.ui.control.StateButton
        AR_ChAx14                  matlab.ui.control.UIAxes
        AR_Ch14                    matlab.ui.control.StateButton
        AR_ChAx15                  matlab.ui.control.UIAxes
        AR_Ch15                    matlab.ui.control.StateButton
        AR_NotAcc                  matlab.ui.control.Label
        AR_Left                    matlab.ui.control.Button
        AR_Right                   matlab.ui.control.Button
        AR_AccCh                   matlab.ui.control.StateButton
        AR_Confirm                 matlab.ui.control.Button
        AR_ChannelParametersPanel  matlab.ui.container.Panel
        AR_ChGrid                  matlab.ui.container.GridLayout
        preEditFieldLabel          matlab.ui.control.Label
        AR_Pre                     matlab.ui.control.NumericEditField
        postEditFieldLabel         matlab.ui.control.Label
        AR_Post                    matlab.ui.control.NumericEditField
        AR_RunCh                   matlab.ui.control.Button
        AR_RunAllParams            matlab.ui.control.Button
        AR_Radio                   matlab.ui.container.ButtonGroup
        AR_Rad1                    matlab.ui.control.RadioButton
        AR_Rad6                    matlab.ui.control.RadioButton
        AR_Rad11                   matlab.ui.control.RadioButton
        AR_Rad2                    matlab.ui.control.RadioButton
        AR_Rad7                    matlab.ui.control.RadioButton
        AR_Rad12                   matlab.ui.control.RadioButton
        AR_Rad3                    matlab.ui.control.RadioButton
        AR_Rad8                    matlab.ui.control.RadioButton
        AR_Rad13                   matlab.ui.control.RadioButton
        AR_Rad4                    matlab.ui.control.RadioButton
        AR_Rad9                    matlab.ui.control.RadioButton
        AR_Rad14                   matlab.ui.control.RadioButton
        AR_Rad5                    matlab.ui.control.RadioButton
        AR_Rad10                   matlab.ui.control.RadioButton
        AR_Rad15                   matlab.ui.control.RadioButton
        AR_RunDefault              matlab.ui.control.Button
        ProcessingTab              matlab.ui.container.Tab
        Pr_Grid                    matlab.ui.container.GridLayout
        Pr_FiltersPanel            matlab.ui.container.Panel
        Pr_FiltersGrid             matlab.ui.container.GridLayout
        Pr_HP                      matlab.ui.control.CheckBox
        Pr_Notch60                 matlab.ui.control.CheckBox
        Pr_Notch120                matlab.ui.control.CheckBox
        Pr_Notch180                matlab.ui.control.CheckBox
        Pr_LP                      matlab.ui.control.CheckBox
        Pr_FilterEx                matlab.ui.control.Button
        Pr_NF                      matlab.ui.control.CheckBox
        BaselinePr_BaselinePanel   matlab.ui.container.Panel
        Pr_BaselineGrid            matlab.ui.container.GridLayout
        Pr_BaselineEx              matlab.ui.control.Button
        BaselineEditFieldLabel     matlab.ui.control.Label
        Pr_BaselineMin             matlab.ui.control.NumericEditField
        toEditFieldLabel           matlab.ui.control.Label
        Pr_BaselineMax             matlab.ui.control.NumericEditField
        Pr_EpochPanel              matlab.ui.container.Panel
        Pr_EpochGrid               matlab.ui.container.GridLayout
        Pr_EpochEx                 matlab.ui.control.Button
        PreSliderLabel             matlab.ui.control.Label
        Pr_EpochPre                matlab.ui.control.Slider
        PostSliderLabel            matlab.ui.control.Label
        Pr_EpochPost               matlab.ui.control.Slider
        ReReferencePanel           matlab.ui.container.Panel
        Pr_RRgrid                  matlab.ui.container.GridLayout
        Pr_RRscheme                matlab.ui.container.ButtonGroup
        Pr_CARmean                 matlab.ui.control.ToggleButton
        Pr_CARmed                  matlab.ui.control.ToggleButton
        Pr_BP                      matlab.ui.control.ToggleButton
        Pr_NoRR                    matlab.ui.control.ToggleButton
        Pr_RRex                    matlab.ui.control.Button
        Pr_PrimarySave             matlab.ui.control.Button
        Pr_SecondarySave           matlab.ui.control.Button
        EPIdentificationTab        matlab.ui.container.Tab
        MI_grid_2                  matlab.ui.container.GridLayout
        MI_ChAx1_2                 matlab.ui.control.UIAxes
        MI_Ch1_2                   matlab.ui.control.StateButton
        MI_ChAx2_2                 matlab.ui.control.UIAxes
        MI_Ch2_2                   matlab.ui.control.StateButton
        MI_ChAx3_2                 matlab.ui.control.UIAxes
        MI_Ch3_2                   matlab.ui.control.StateButton
        MI_ChAx4_2                 matlab.ui.control.UIAxes
        MI_Ch4_2                   matlab.ui.control.StateButton
        MI_ChAx5_2                 matlab.ui.control.UIAxes
        MI_Ch5_2                   matlab.ui.control.StateButton
        MI_ChAx6_2                 matlab.ui.control.UIAxes
        MI_Ch6_2                   matlab.ui.control.StateButton
        MI_ChAx7_2                 matlab.ui.control.UIAxes
        MI_Ch7_2                   matlab.ui.control.StateButton
        MI_ChAx8_2                 matlab.ui.control.UIAxes
        MI_Ch8_2                   matlab.ui.control.StateButton
        MI_ChAx9_2                 matlab.ui.control.UIAxes
        MI_Ch9_2                   matlab.ui.control.StateButton
        MI_ChAx10_2                matlab.ui.control.UIAxes
        MI_Ch10_2                  matlab.ui.control.StateButton
        MI_ChAx11_2                matlab.ui.control.UIAxes
        MI_Ch11_2                  matlab.ui.control.StateButton
        MI_ChAx12_2                matlab.ui.control.UIAxes
        MI_Ch12_2                  matlab.ui.control.StateButton
        MI_ChAx13_2                matlab.ui.control.UIAxes
        MI_Ch13_2                  matlab.ui.control.StateButton
        MI_ChAx14_2                matlab.ui.control.UIAxes
        MI_Ch14_2                  matlab.ui.control.StateButton
        MI_ChAx15_2                matlab.ui.control.UIAxes
        MI_Ch15_2                  matlab.ui.control.StateButton
        MI_BadCh_2                 matlab.ui.control.Label
        MI_Left_2                  matlab.ui.control.Button
        MI_Right_2                 matlab.ui.control.Button
        MI_Confirm_2               matlab.ui.control.Button
        FitModelsTab               matlab.ui.container.Tab
        FM_Grid                    matlab.ui.container.GridLayout
        FM_Ax                      matlab.ui.control.UIAxes
        FM_UploadCSV               matlab.ui.control.Button
        FM_ProcessCSV              matlab.ui.control.Button
        FM_Left                    matlab.ui.control.Button
        FM_Right                   matlab.ui.control.Button
        FM_R2                      matlab.ui.control.Label
        FM_AccCh                   matlab.ui.control.StateButton
        FM_NumChAcc                matlab.ui.control.Label
        FM_Confirm                 matlab.ui.control.Button
        FM_ProcessDefault          matlab.ui.control.Button
        FM_ChannelParametersPanel  matlab.ui.container.Panel
        FM_ChGrid                  matlab.ui.container.GridLayout
        FM_C1                      matlab.ui.control.Label
        FM_C1min                   matlab.ui.control.NumericEditField
        FM_C1max                   matlab.ui.control.NumericEditField
        FM_Min                     matlab.ui.control.Label
        FM_C2max                   matlab.ui.control.NumericEditField
        FM_C2min                   matlab.ui.control.NumericEditField
        FM_C2                      matlab.ui.control.Label
        FM_C3                      matlab.ui.control.Label
        FM_C3min                   matlab.ui.control.NumericEditField
        FM_C3max                   matlab.ui.control.NumericEditField
        FM_C4                      matlab.ui.control.Label
        FM_C4min                   matlab.ui.control.NumericEditField
        FM_C4max                   matlab.ui.control.NumericEditField
        C1PolaritySwitchLabel      matlab.ui.control.Label
        FM_Pol                     matlab.ui.control.Switch
        FM_C1start                 matlab.ui.control.NumericEditField
        FM_C4start                 matlab.ui.control.NumericEditField
        FM_C2start                 matlab.ui.control.NumericEditField
        FM_C3start                 matlab.ui.control.NumericEditField
        FM_Start                   matlab.ui.control.Label
        FM_Max                     matlab.ui.control.Label
        FM_ComponentWidth          matlab.ui.control.Label
        FM_RunCh                   matlab.ui.control.Button
        FM_RevertCh                matlab.ui.control.Button
        Components14Label          matlab.ui.control.Label
        FM_NComp                   matlab.ui.control.NumericEditField
        FM_Prev                    matlab.ui.control.StateButton
        FM_SaveCur                 matlab.ui.control.Button
        QuantifyTab                matlab.ui.container.Tab
        Q_Panel                    matlab.ui.container.GridLayout
        Q_QuantSave                matlab.ui.control.Button
        filenameEditFieldLabel     matlab.ui.control.Label
        Q_Fn                       matlab.ui.control.EditField
    end

    
    properties (Access = private)
    % general subject information
        SubjDir; SubjFileDir; Subj;
        
    % montage information
        MontageProbes; MontageIdx; MontageBadChans; MontageGoodChans; MontageNumChans;
        
    % stimulation information
        SAmplitude; SAnode; SCathode; SOnsetsSamps; SPulseWidth;
        
    % data
        RawData; RawDataCtr; RawFs; RawRSidx;
        
    % manual inspection tab
        ManualIns;
        
    % noise inspection tab
        NoiseInsPW; NoiseInsYP; NoiseInsSP; NoiseInsAmpSum; NoiseInsAmpRMS; NoiseInsFFT;

    % artifact removal tab
        ArtremData; ArtremPP; Artrem; ArtremChck; %ArtremTable; ArtremEpoch; ArtremMean; 
%         ArtremInit = struct; ArtremBR; ArtremDM; ArtremPP;
%         ArtremHasBeenChanged; ArtremAddlTableLine; ArtremCurIdx; ArtremAcceptedCh;
%         ArtremStartInds = cell(1,1); ArtremEndInds = cell(1,1); ArtremMaxLocation; ArtremTemplateArrayCell = cell(1,1);
        
    % filter tab
%         FiltData; FiltEpochData; FiltBLsub; 
        FiltBLlims; FiltRRscheme;
        
    % processed data
        ProcessedData; TEpoch; EPmean; EPmedian; EPstd;
        
    % model fitting tab
        ModelEqn; ModelData; ModelTVals; ModelT10; ModelR2; ModelCMin; ModelCMax;
        ModelCStart; ModelNComp; ModelPol; ModelHasBeenChanged; ModelInit = struct;
        ModelCurIdx; ModelAcceptedCh; ModelEPCh;
        
    end
    
    methods (Access = private)
        
        function [] = CallStartup(app, fn)
        % call startup calculations and save variables
            [app.SAmplitude, app.SAnode, app.SCathode, app.RawData, app.RawFs, app.SOnsetsSamps, app.RawRSidx,...
                app.SPulseWidth, app.MontageBadChans, app.MontageGoodChans, app.NoiseInsPW, ...
                app.NoiseInsYP, app.NoiseInsSP, app.NoiseInsAmpSum, app.NoiseInsAmpRMS, app.NoiseInsFFT] = ...
                newFileCalcs(app.SubjFileDir, fn, fullfile(app.SubjDir, sprintf('%s_Montage.mat', app.Subj)), app.UpdateWindow);
            app.MI_Setup;
            app.AR_Setup;    
        end
        
        function [] = LoadData(app, fn)
        % load data and save variables
            s = load(fullfile(app.SubjFileDir, fn));
            app.SAmplitude = s.amplitude; app.SAnode = s.anode; app.SCathode = s.cathode;
            app.RawData = s.data; app.RawFs = s.fs; app.SOnsetsSamps = s.onsets_samps;
            app.SPulseWidth = s.pulse_width; app.MontageNumChans = size(app.RawData, 2);
        % load bad channels and save variables
            s = load(fullfile(app.SubjFileDir, 'badchans.mat'));
            app.MontageBadChans = s.badchans; app.MontageGoodChans = s.goodchans; clearvars s
        % load resting state idx;
            s = load(fullfile(app.SubjFileDir, 'rs_data.mat'), 'rs_idx');
            app.RawRSidx = s.rs_idx;
        % load noise calculations and save variables
            s = load(fullfile(app.SubjFileDir, 'noise_vals.mat'));
            app.NoiseInsPW = s.pw_data; app.NoiseInsYP = s.yp; app.NoiseInsSP = s.sp; 
            app.NoiseInsAmpSum = s.ampsum; app.NoiseInsAmpRMS = s.amprms; app.NoiseInsFFT = s.fft_vals;
        end
        
        function [] = NT_Load(app)
        % load chosen filter options and save variables
            s = load(fullfile(app.SubjFileDir, 'filter_opts.mat'));
        % fill form values
            app.NT_HP.Value = s.HP; app.NT_LP.Value = s.LP;
            app.NT_Notch60.Value = s.N(1); app.NT_Notch120.Value = s.N(2);
            app.NT_Notch180.Value = s.N(3);
            app.Pr_FillVals;
        end
        
        function [] = AR_Load(app)
        % load artifact removed data and save variables
            s = load(fullfile(app.SubjFileDir, 'artrem_data.mat'));
            app.ArtremData = s.data_artrem;
%             app.ArtremEpoch = epochData(app.ArtremData, app.SOnsetsSamps, 0.001, 0.01, app.RawFs);
%             app.ArtremChck = s.chck; loc_chck = app.ArtremChck;
%             loc_chck(isnan(loc_chck)) = 0; loc_chck = logical(loc_chck);
%             app.ArtremMean = nan(size(app.ArtremEpoch, 1), size(app.ArtremEpoch, 2));
%             for ii = 1:size(app.ArtremEpoch, 2)
%                 loc = squeeze(app.ArtremEpoch(:, ii, :));
%                 app.ArtremMean(:, ii) = nanmean(loc(:, loc_chck(:, ii)), 2);
%             end
%             app.ArtremMean = mean(app.ArtremEpoch, 3);
        % load artifact removal parameters and save variables
%             s = load(fullfile(app.SubjFileDir, 'artrem_param.mat'));
            app.AR_Setup;
            app.Artrem.AddPP(s.prepost); % SAVE AS PREPOST
%             app.ArtremTable = s.artrem_table; 
%             app.ArtremBR = s.BR; app.ArtremDM = s.DM; app.ArtremPP = s.PP; 
%             app.ArtremAcceptedCh = s.accepted_ch;
%             app.ArtremStartInds = s.startInds; app.ArtremEndInds = s.endInds;
%             app.ArtremMaxLocation = s.maxLocation; app.ArtremTemplateArrayCell = s.templateArrayCell;
%             app.ArtremHasBeenChanged = false(size(app.ArtremDM));
%             app.FillArtremInit;
%             app.ArtremCurIdx = 1; app.ArtremPlot(); app.Pr_FilterEx.Enable = true;
            
        end
        
        function [] = Pr_Load(app)
        % load filtered data and save variables
            s = load(fullfile(app.SubjFileDir, 'data_filt.mat'), 'data', 'proc_params', 'tEpoch');
            app.ProcessedData = s.data; app.TEpoch = s.tEpoch;
            app.Pr_EpochPre.Value = app.TEpoch(1); app.Pr_EpochPost.Value = app.TEpoch(end);
            app.Pr_HP.Value = s.proc_params.HP;
            app.Pr_Notch60.Value = s.proc_params.N(1);
            app.Pr_Notch120.Value = s.proc_params.N(2); app.Pr_Notch180.Value = s.proc_params.N(3);
            app.Pr_LP.Value = s.proc_params.LP;
            app.setNF;
            app.FiltBLlims = s.proc_params.BL; app.Pr_BaselineMin.Value = app.FiltBLlims(1);
            app.Pr_BaselineMin.Value = app.FiltBLlims(2);
            app.FiltRRscheme = s.proc_params.RR;
            switch app.FiltRRscheme
                case 'mean'
                    app.Pr_CARmean.Value = true; app.Pr_CARmed.Value = false;
                    app.Pr_BP.Value = false; app.Pr_NoRR.Value = false;
                case 'median'
                    app.Pr_CARmean.Value = false; app.Pr_CARmed.Value = true;
                    app.Pr_BP.Value = false; app.Pr_NoRR.Value = false;
                case 'bipolar'
                    app.Pr_CARmean.Value = false; app.Pr_CARmed.Value = false;
                    app.Pr_BP.Value = true; app.Pr_NoRR.Value = false;
                case 'none'
                    app.Pr_CARmean.Value = false; app.Pr_CARmed.Value = false;
                    app.Pr_BP.Value = false; app.Pr_NoRR.Value = true;
            end
        end
        
        function [] = FM_Load(app)
        % load model data and save variables
            s = load(fullfile(app.SubjFileDir, 'model_data.mat'));
            app.ModelTVals = s.t; app.ModelData = s.models_fit; app.ModelR2 = s.r2;
            app.ModelT10 = find(app.TEpoch >= 0.01);
        % load model parameters and save variables
            s = load(fullfile(app.SubjFileDir, 'model_param.mat'));
            app.ModelEqn = s.eqns; app.ModelCMin = s.cmin; app.ModelCMax = s.cmax;
            app.ModelCStart = s.cstart; app.ModelNComp = s.ncomp; app.ModelPol = s.pol;
            app.ModelEPCh = s.EPchans; app.ModelCurIdx = 1; 
            app.ModelAcceptedCh = nan(1, app.MontageNumChans); 
            app.ModelAcceptedCh(app.ModelEPCh) = true;
            app.ModelInit.Eqn = app.ModelEqn; app.ModelInit.R2 = app.ModelR2; app.ModelInit.Data = app.ModelData;
            app.ModelInit.CMin = app.ModelCMin; app.ModelInit.CMax = app.ModelCMax; app.ModelInit.CStart = app.ModelCStart;
            app.ModelInit.NComp = app.ModelNComp; app.ModelInit.Pol = app.ModelPol;
            app.ModelHasBeenChanged = false(size(app.ModelEPCh));
        end
        
        function [] = MI_Setup(app)
        % gather axes
            ManualInsAx = {app.MI_ChAx1, app.MI_ChAx2, app.MI_ChAx3, ...
                app.MI_ChAx4, app.MI_ChAx5, app.MI_ChAx6, app.MI_ChAx7, ...
                app.MI_ChAx8, app.MI_ChAx9, app.MI_ChAx10, app.MI_ChAx11, ...
                app.MI_ChAx12, app.MI_ChAx13, app.MI_ChAx14, app.MI_ChAx15};
            ManualInsBut = {app.MI_Ch1, app.MI_Ch2, app.MI_Ch3, ...
                app.MI_Ch4, app.MI_Ch5, app.MI_Ch6, app.MI_Ch7, ...
                app.MI_Ch8, app.MI_Ch9, app.MI_Ch10, app.MI_Ch11, ...
                app.MI_Ch12, app.MI_Ch13, app.MI_Ch14, app.MI_Ch15};
        % center data
            app.RawDataCtr = app.RawData - median(app.RawData(app.RawRSidx, :));
        % setup plots and create object
            app.ManualIns = ManualInsObj(ManualInsAx, ManualInsBut, app.MI_BadCh, app.MI_Left, app.MI_Right, app.MI_Switch, ...
                app.MontageNumChans, app.RawFs, app.RawRSidx, app.MontageBadChans, app.MontageGoodChans, app.RawDataCtr);
        end
        
        function [] = NT_Setup(app)
        % calculate percent with significant low frequency noise
            cutoff = fdr(app.NoiseInsYP, .05);
            pct = sum(app.NoiseInsYP(app.MontageGoodChans) <= cutoff)/length(app.MontageGoodChans);
            app.NT_InterceptGauge.Value = pct*100;
            cutoff = fdr(app.NoiseInsSP, .05);
            pct = sum(app.NoiseInsSP(app.MontageGoodChans) <= cutoff)/length(app.MontageGoodChans);
            app.NT_SlopeGauge.Value = pct*100;
        % plot power spectral density
            plot(app.NT_AxLineNoise, app.NoiseInsPW(1:200, app.MontageGoodChans));
            hold(app.NT_AxLineNoise, 'on')
            plot(app.NT_AxLineNoise, mean(app.NoiseInsPW(1:200, app.MontageGoodChans), 2), 'k', 'LineWidth', 3);
        % plot FFT
            L = length(app.NoiseInsFFT); F = (app.RawFs*(0:(L/2))/L)';
            F_use = F >= 250 & F <= 2000;
            plot(app.NT_AxHF, F(F_use), app.NoiseInsFFT(F_use, app.MontageGoodChans))
            plot(app.NT_AxHF, F(F_use), mean(app.NoiseInsFFT(F_use, app.MontageGoodChans), 2), 'k', 'LineWidth', 3);
        % plot high frequency noise histograms
            histogram(app.NT_AxHFrms, app.NoiseInsAmpSum, 100, 'EdgeColor', 'k', 'DisplayStyle', ...
                'stairs', 'Normalization', 'probability', 'LineWidth', 2)
            histogram(app.NT_AxHFsum, app.NoiseInsAmpRMS, 100, 'EdgeColor', 'k', 'DisplayStyle', ...
                'stairs', 'Normalization', 'probability', 'LineWidth', 2)
        end
        
        function [] = AR_Setup(app)
        % gather axes
            ArtremAx = {app.AR_ChAx1, app.AR_ChAx2, app.AR_ChAx3, ...
                app.AR_ChAx4, app.AR_ChAx5, app.AR_ChAx6, app.AR_ChAx7, ...
                app.AR_ChAx8, app.AR_ChAx9, app.AR_ChAx10, app.AR_ChAx11, ...
                app.AR_ChAx12, app.AR_ChAx13, app.AR_ChAx14, app.AR_ChAx15};
            ArtremBut = {app.AR_Ch1, app.AR_Ch2, app.AR_Ch3, ...
                app.AR_Ch4, app.AR_Ch5, app.AR_Ch6, app.AR_Ch7, ...
                app.AR_Ch8, app.AR_Ch9, app.AR_Ch10, app.AR_Ch11, ...
                app.AR_Ch12, app.AR_Ch13, app.AR_Ch14, app.AR_Ch15};
            ArtremRad = {app.AR_Rad1, app.AR_Rad2, app.AR_Rad3, app.AR_Rad4, ...
                app.AR_Rad5, app.AR_Rad6,app.AR_Rad7, app.AR_Rad8, app.AR_Rad9, ...
                app.AR_Rad10, app.AR_Rad11, app.AR_Rad12, app.AR_Rad13, ...
                app.AR_Rad14, app.AR_Rad15};
        % create object
            app.Artrem = ArtRepObj(ArtremAx, ArtremBut, ArtremRad, app.AR_Radio, ...
                app.AR_NotAcc, app.AR_Left, app.AR_Right, ...
                app.AR_Pre, app.AR_Post, app.UpdateWindow, app.MontageNumChans, app.RawFs, ...
                app.SOnsetsSamps, app.MontageGoodChans, [app.SAnode app.SCathode]);
        end
        
%         function [] = ArtremPlot(app)
%         % plot channel's artifact removal data
%             cla(app.AR_Ax, 'reset');
%             n = app.MontageGoodChans(app.ArtremCurIdx); % channel #
%             loc_chck = app.ArtremChck(:, n); % get good/bad trial designations
%         % if all values are NaN, its a stim channel
%             if all(isnan(loc_chck))
%                 app.AR_Ax.Title.String = 'Stim Channel - No Traces';
%                 app.ArtremAcceptedCh(n) = true;
%                 app.AR_RunCh.Enable = false;
%         % otherwise, plot trials
%             else
%                 loc_nan = isnan(loc_chck); % find NaN (stim) trials
%                 loc_chck(loc_nan) = 0; loc_chck = logical(loc_chck); % convert to logical
%         % plot bad trials in red
%                 if any(~loc_chck)
%                     plot(app.AR_Ax, -.005:(1/app.RawFs):.02, ...
%                         squeeze(app.ArtremEpoch(:, n, ~loc_chck)) - squeeze(mean(app.ArtremEpoch(:, n, ~loc_chck)))', 'r');
%                 end
%                 hold(app.AR_Ax, 'on')
%         % plot good trials in green
%                 if any(loc_chck)
%                     plot(app.AR_Ax, -.005:(1/app.RawFs):.02, ...
%                         squeeze(app.ArtremEpoch(:, n, loc_chck))- squeeze(mean(app.ArtremEpoch(:, n, loc_chck)))', 'g');
%                 end
%         % plot mean in black
%                 plot(app.AR_Ax, -.005:(1/app.RawFs):.02, ...
%                     app.ArtremMean(:, n) - mean(app.ArtremMean(:, n)), 'k', 'LineWidth', 2);
%         % set y-axes
%                 mini = min(app.ArtremMean(:, n) - mean(app.ArtremMean(:, n))); 
%                 maxi = max(app.ArtremMean(:, n) - mean(app.ArtremMean(:, n)));
%                 if isnan(mini)
%                     mini = min(min(app.ArtremEpoch(:, n, :)));
%                     maxi = max(max(app.ArtremEpoch(:, n, :)));
%                 end
%                 ylim(app.AR_Ax, [mini - abs(.1*mini), maxi + abs(.1*maxi)])
%                 xlim(app.AR_Ax, [-5e-3 20e-3])
%         % set labels
%                 app.AR_Ax.Title.String = sprintf('Channel %d', n);
% %                 app.AR_BRmin.Value = app.ArtremBR(n, 1); app.AR_BRmax.Value = app.ArtremBR(n, 2);
% %                 if app.ArtremDM(n); app.AR_Dbscan.Value = 'corr'; else; app.AR_Dbscan.Value = 'eucl'; end
%                 app.AR_Pre.Value = app.ArtremPP(n, 1); app.AR_Post.Value = app.ArtremPP(n, 2);
%                 app.AR_RunCh.Enable = true; 
%                 app.AR_TrialPass.Text = sprintf('%d / %d trials pass', sum(loc_chck), length(loc_chck));
%                 app.AR_AccCh.Value = app.ArtremAcceptedCh(n);
%             end
%         % enable/disable R/L buttons
%             app.RLSettings(app.ArtremCurIdx, max(app.MontageGoodChans), ...
%                 length(app.MontageGoodChans) == 1, app.AR_Left, app.AR_Right);
%         end
        
        function [] = FM_Plot(app)
        % plot channel's fit model data
            cla(app.FM_Ax, 'reset');
            n = app.ModelEPCh(app.ModelCurIdx);
        % plot evoked potential in black
            plot(app.FM_Ax, app.ModelTVals, app.EPmean(app.ModelT10:end, n), 'k', 'LineWidth', 2);
            hold(app.FM_Ax, 'on'); loc_r2 = app.ModelR2(n);
        % if mnodel fit is good, plot in blue
            if loc_r2 >= 0.8
                plot(app.FM_Ax, app.ModelTVals, app.ModelData(:, n), 'b', 'LineWidth', 2);
        % if model fit is bad, plot in red
            else
                plot(app.FM_Ax, app.ModelTVals, app.ModelData(:, n), 'r', 'LineWidth', 2);
            end
        % update labels
            app.FM_Ax.Title.String = sprintf('Channel %d', n);
            app.FM_C1min.Value = app.ModelCMin(n, 1); app.FM_C2min.Value = app.ModelCMin(n, 2);
            app.FM_C3min.Value = app.ModelCMin(n, 3); app.FM_C4min.Value = app.ModelCMin(n, 4);
            app.FM_C1max.Value = app.ModelCMax(n, 1); app.FM_C2max.Value = app.ModelCMax(n, 2);
            app.FM_C3max.Value = app.ModelCMax(n, 3); app.FM_C4max.Value = app.ModelCMax(n, 4);
            app.FM_C1start.Value = app.ModelCStart(n, 1); app.FM_C2start.Value = app.ModelCStart(n, 2);
            app.FM_C3start.Value = app.ModelCStart(n, 3); app.FM_C4start.Value = app.ModelCStart(n, 4);
            if app.ModelPol(n); app.FM_Pol.Value = '+'; else; app.FM_Pol.Value = '-'; end
            app.FM_NComp.Value = app.ModelNComp(n);
            app.FM_R2.Text = sprintf('R2 = %0.4f', app.ModelR2(n));
            app.FM_AccCh.Value = app.ModelAcceptedCh(n);
        % enable/disable buttons
            app.FM_RunCh.Enable = true;
            app.RLSettings(app.ModelCurIdx, length(app.ModelEPCh), length(app.ModelEPCh) == 1, app.FM_Left, app.FM_Right);
            app.FM_RevertCh.Enable = app.ModelHasBeenChanged(app.ModelCurIdx);
        end
        
        function [] = Pr_FillVals(app)
        % fill values in processing tab based on filter options
            app.Pr_HP.Value = app.NT_HP.Value; app.Pr_LP.Value = app.NT_LP.Value;
            app.Pr_Notch60.Value = app.NT_Notch60.Value; 
            app.Pr_Notch120.Value = app.NT_Notch120.Value;
            app.Pr_Notch180.Value = app.NT_Notch180.Value;
            app.Pr_SetNF;
        end
        
        function [] = Pr_SetNF(app)
            if sum([app.Pr_HP.Value app.Pr_LP.Value app.Pr_Notch60.Value ...
                    app.Pr_Notch120.Value app.Pr_Notch180.Value]) == 0
                app.Pr_NF.Value = true;
            else
                app.Pr_NF.Value = false;
            end
        end
        
%         function [] = FillArtremInit(app)
%         % fill initial artrem with empty vectors or saved variables
%             if isempty(app.ArtremData)
%                 app.ArtremInit.artrem_table = []; app.ArtremInit.data_artrem = [];
%                 app.ArtremInit.artrem_epoch = []; app.ArtremInit.artrem_mean = [];
%                 app.ArtremInit.chck = []; app.ArtremInit.BR = [];  app.ArtremInit.DM = [];
%                 app.ArtremInit.PP = []; app.ArtremInit.templateArrayCell = cell(1, app.MontageNumChans);
%                 app.ArtremInit.startInds = cell(1, app.MontageNumChans); app.ArtremInit.endInds = cell(1, app.MontageNumChans);
%                 app.ArtremInit.maxLocation = [];
%             else
%                 app.ArtremInit.data_artrem = app.ArtremData; app.ArtremInit.artrem_table = app.ArtremTable;
%                 app.ArtremInit.artrem_epoch = app.ArtremEpoch; app.ArtremInit.artrem_mean = app.ArtremMean;
%                 app.ArtremInit.chck = app.ArtremChck; app.ArtremInit.BR = app.ArtremBR; app.ArtremInit.DM = app.ArtremDM;
%                 app.ArtremInit.PP = app.ArtremPP; app.ArtremInit.templateArrayCell = app.ArtremTemplateArrayCell;
%                 app.ArtremInit.startInds = app.ArtremStartInds; app.ArtremInit.endInds = app.ArtremEndInds;
%                 app.ArtremInit.maxLocation = app.ArtremMaxLocation;
%             end
%         end
        
%         function [] = ResetArtrem(app)
%         % set artrem to data from new run of all channels (process with defaults or CSV)
%             app.ArtremEpoch = epochData(app.ArtremData, app.SOnsetsSamps, 0.005, 0.02, app.RawFs);
%             loc_chck = app.ArtremChck;
%             loc_chck(isnan(loc_chck)) = 0; loc_chck = logical(loc_chck);
%             app.ArtremMean = nan(size(app.ArtremEpoch, 1), size(app.ArtremEpoch, 2));
%             for ii = 1:size(app.ArtremEpoch, 2)
%                 loc = squeeze(app.ArtremEpoch(:, ii, :));
%                 app.ArtremMean(:, ii) = nanmean(loc(:, loc_chck(:, ii)), 2);
%             end
%             app.ArtremAcceptedCh = false(size(app.ArtremData, 2), 1);
%             app.ArtremCurIdx = 1;
%             app.ArtremHasBeenChanged = false(size(app.MontageGoodChans));
%             app.ArtremAddlTableLine = zeros(size(app.MontageGoodChans));
%         % set up plots, buttons, and labels
%             app.ArtremPlot();
%             app.AR_NumChAcc.Enable = true; app.AR_AccCh.Enable = true;
%             app.AR_NumChAcc.Text = sprintf('%d / %d good channels accepted', 0, length(app.MontageGoodChans));
%             app.AR_SaveCur.Enable = true;
%             app.FillArtremInit;
%         end
   
%         function [] = CalcMeans(app)
%         % calculate mean, median, and standard deviation, excluding bad trials
%             app.EPmean = nan(size(app.ProcessedData, 1), size(app.ProcessedData, 2));
%             app.EPstd = app.EPmean; app.EPmedian = app.EPmedian;
%             for ii = 1:app.MontageNumChans
%                 loc_chck = app.ArtremChck(:, ii);
%                 loc_chck(isnan(loc_chck)) = 0; loc_chck = logical(loc_chck);
%                 loc_dat = squeeze(app.Data(:, ii, :));
%                 app.EPmean(:, ii) = nanmean(loc_dat(:, loc_chck), 2);
%                 app.EPmedian(:, ii) = nanmedian(loc_dat(:, loc_chck), 2);
%                 app.EPstd(:, ii) = nanstd(loc_dat(:, loc_chck), [], 2);
%             end
%         end
        
        function [] = Pr_Filter(app, sos, g)
        % implement a pre-specified filter using sos/g inputs for filtfilt
            n = ceil(size(app.ProcessedData, 2)/10);
            for ii = 1:n % chunk into groups of channels to save processing power
                loc_range = (1:10) + 10*(ii - 1); 
                loc_range(loc_range > size(app.ProcessedData, 2)) = [];
                loc_range(ismember(loc_range, app.MontageBadChans)) = [];
                loc_range(ismember(loc_range, app.SAnode) | ...
                    ismember(loc_range, app.SCathode)) = []; % assumption of one stim pair
                app.UpdateWindow.Value = vertcat({sprintf('Filtering channels %d-%d', ...
                    loc_range(1), loc_range(end))}, app.UpdateWindow.Value); pause(0.1);
                app.ProcessedData(:, loc_range) = filtfilt(sos, g, app.ProcessedData(:, loc_range));
            end
        end
        
        function [] = RLSettings(~, curval, maxval, minflag, L, R)
            
            if minflag
                L.Enable = false; R.Enable = false;
            elseif curval == 1
                L.Enable = false; R.Enable = true;
            elseif curval == maxval
                L.Enable = true; R.Enable = false;
            else
                L.Enable = true; R.Enable = true;
            end
            
        end
        
        function [] = Pr_CallSave(app, fn)
            SaveProcessed(fn, app.ProcessedData, app.TEpoch, app.SAmplitude, app.SAnode, app.SCathode, app.RawFs, ...
                app.SOnsetsSamps, app.Pr_HP.Value, app.Pr_LP.Value, app.Pr_Notch60.Value, app.Pr_Notch120.Value, ...
                app.Pr_Notch180.Value, app.FiltBLlims, app.FiltRRscheme);
            process_calc = true; save(fullfile(app.SubjFileDir, 'progress_report.mat'), ...
                'process_calc', '-append');
        end
        
        function [] = FM_CallSaveParams(app)
            SaveModelParams(fullfile(app.SubjFileDir, 'model_params.mat'), app.ModelEqn, ...
                app.ModelCMin, app.ModelCMax, app.ModelCStart, app.ModelNComp, app.ModelPol, app.ModelEPCh);
        end
        
%         function [] = AR_CallSaveParams(app)
%             SaveArtremParams(fullfile(app.SubjFileDir, 'artrem_param.mat'), app.ArtremTable, app.ArtremBR, ...
%                 app.ArtremDM, app.ArtremPP, app.ArtremAcceptedCh, app.ArtremTemplateArrayCell, ...
%                 app.ArtremStartInds, app.ArtremEndInds, app.ArtremMaxLocation);
%         end
        
%         function [] = RunARch(app, ch_out, ch_in, br, dm, pre, post, bads)
%             allnanch = find(all(isnan(app.RawData), 1));
%             
%             ch_in(ismember(ch_in, allnanch)) = [];
%             if any(~ismember(ch_out, ch_in))
%                 error('All out channels must be members of in channels.')
%             end
%             if any(isnan([ch_out ch_in]))
%                 error('Empty channels');
%             end
%             if length(ch_in) == 1
%                 stim_trodes = repmat(2, length(app.SAnode), 2);
%             else
%                 stim_trodes = arrayfun(@(x) find(x == ch_in, 1), [app.SAnode app.SCathode], 'UniformOutput', false);
%                 stim_trodes(cellfun(@isempty, stim_trodes)) = {length(ch_in)+1};
%                 stim_trodes = cell2mat(stim_trodes);
%             end
%             
%             if isequal(repmat([pre post], length(ch_in), 1), app.ArtremPP(ch_in, :))
%                 [loc_artrem, loc_chck] = analyFunc.process_from_template(app.RawData(:, ch_in), app.UpdateWindow, ...
%                     dm, br, app.ArtremTemplateArrayCell, app.ArtremStartInds, app.ArtremEndInds, app.ArtremMaxLocation, ch_in);
%                 tempFlag = false;
%             else
%                 [loc_artrem, loc_chck, loc_templateArrayCell, loc_startInds, loc_endInds, ...
%                     loc_maxLocation] = analyFunc.template_subtract(app.RawData(:, ch_in), app.UpdateWindow, app.RawFs, ...
%                     pre, post, br, dm, stim_trodes, app.SOnsetsSamps, bads);
%                 tempFlag = true;
%             end
%             
% 
%             if isequal(ch_out, ch_in)
%                 out_idx = true(size(ch_in));
%             else
%                 out_idx = ismember(ch_in, ch_out);
%             end
%                 
%             app.ArtremData(:, ch_out) = loc_artrem(:, out_idx);
%             app.ArtremChck(:, ch_out) = loc_chck(:, out_idx);
%             if tempFlag
%                 app.ArtremTemplateArrayCell(ch_out) = loc_templateArrayCell(out_idx);
%                 for ii = 1:length(loc_startInds)
%                     app.ArtremStartInds{ii}(:, ch_out) = loc_startInds{ii}(out_idx);
%                     app.ArtremEndInds{ii}(:, ch_out) = loc_endInds{ii}(out_idx);
%                 end
%                 app.ArtremMaxLocation(ch_out) = loc_maxLocation;
%             end
%             
%             app.ArtremBR(ch_out, :) = repmat([br(1) br(end)], length(ch_out), 1); 
%             app.ArtremPP(ch_out, :) = repmat([pre post], length(ch_out), 1);
%             if strcmp(dm, 'eucl')
%                 dm = false;
%             else
%                 dm = true;
%             end
%             app.ArtremDM(ch_out) = dm;
%             app.ArtremEpoch(:, ch_out, :) = epochData(app.ArtremData(:, out_idx), app.SOnsetsSamps, 0.001, 0.01, app.RawFs);
%             for ii = ch_out
%                 loc_chck = app.ArtremChck(:, ii);
%                 loc_chck(isnan(loc_chck)) = 0; loc_chck = logical(loc_chck);
%                 app.ArtremMean(:, ii) = mean(app.ArtremEpoch(:, ii, loc_chck), 3);
%             end
%         end
%         
%         function [] = AddARTableLine(app, idx, br, dm, pre, post)
%             ch = app.MontageGoodChans(idx);
%             if length(ch) == 1
%                 chans_out = convertCharsToStrings(num2str(ch)); 
%             else
%                 chans_out = convertCharsToStrings(sprintf('[1:%d]', max(ch)));
%             end
%             chans_in = chans_out;
%             dist_metric = convertCharsToStrings(dm); br_min = br(1); br_max = br(end);
%             if any(app.ArtremAddlTableLine(idx) ~= 0)
%                 app.ArtremTable(app.ArtremAddlTableLine(idx), :) = [];
%             end
%             if isempty(app.ArtremTable)
%                 app.ArtremTable = table(chans_out, chans_in, br_min, br_max, dist_metric, pre, post);
%             else
%                 app.ArtremTable = [app.ArtremTable; ...
%                     table(chans_out, chans_in, br_min, br_max, dist_metric, pre, post)];
%                 app.ArtremAddlTableLine(idx) = height(app.ArtremTable);
%             end
%         end

        function [] = FM_Init(app, findEPflag)
            if ~isempty(app.ModelT10)
                cont = CallDialogBox('This will overwrite any changes you have made in current models');
            else
                cont = true;
            end
            if cont
                if findEPflag
                    app.ModelEPCh = find(sum(abs(app.EPmean(app.ModelT10:end, :)) > 0.5) >= app.RawFs*0.1);
                end
                cla(app.FM_Ax, 'reset'); pause(0.1);
%                 app.FM_ProcessCSV.Enable = false; app.FM_ProcessDefault.Enable = false; 
                app.ModelT10 = find(app.TEpoch > 0.01, 1);
                app.ModelTVals = app.TEpoch(app.ModelT10:end)' - app.TEpoch(app.ModelT10);
                app.ModelEqn = cell(app.MontageNumChans, 1); app.ModelR2 = nan(app.MontageNumChans, 1);
                app.ModelData = nan(length(app.ModelTVals), app.MontageNumChans);
                app.ModelNComp = nan(app.MontageNumChans, 1);
                app.ModelPol = false(app.MontageNumChans, 1);
                ct = 1;
                for ii = app.ModelEPCh
                    app.UpdateWindow.Value = vertcat(app.UpdateWindow.Value, {sprintf('Channel %d of %d\n', ...
                        ct, length(app.ModelEPCh))}); pause(0.01);
                    app.FM_RunChan(ii);
                    ct = ct + 1;
                end
                app.ModelAcceptedCh = false(app.MontageNumChans, 1);
                app.ModelCurIdx = 1; app.ModelHasBeenChanged = false(size(app.ModelEPCh));
                app.FM_Plot();
                app.FM_NumChAcc.Enable = true; app.FM_AccCh.Enable = true;
                app.FM_NumChAcc.Text = sprintf('%d / %d EP channels accepted', 0, length(app.ModelEPCh));
                app.FM_SaveCur.Enable = true;
                app.ModelInit.Eqn = app.ModelEqn; app.ModelInit.R2 = app.ModelR2; app.ModelInit.Data = app.ModelData;
                app.ModelInit.CMin = app.ModelCMin; app.ModelInit.CMax = app.ModelCMax; app.ModelInit.CStart = app.ModelCStart;
                app.ModelInit.NComp = app.ModelNComp; app.ModelInit.Pol = app.ModelPol;
                app.FM_ProcessDefault.Enable = true;
            end
        end
        
        function [] = FM_RunChan(app, idx)
            if isempty(app.ModelNComp) || isnan(app.ModelNComp(idx))
                ncomp = [];
            else
                ncomp = app.ModelNComp(idx);
            end
            if isempty(app.ModelPol) || isnan(app.ModelNComp(idx))
                pol = [];
            else
                pol = app.ModelPol(idx);
            end
            [app.ModelData(:, idx), app.ModelNComp(idx), app.ModelPol(idx), app.ModelEqn{idx}, app.ModelR2(idx)] = ...
                componentFit(app.EPmean(:, idx), app.TEpoch, 'cmin', app.ModelCMin(idx, :), 'cmax', app.ModelCMax(idx, :), ...
                'cstr', app.ModelCStart(idx, :), 'numc', ncomp, 'pol', pol);
        end
        
%         function [] = artrep(app, ch, pre, post)
%             cla(app.AR_Ax, 'reset');  pause(0.001);
%             pre_samps = round(pre*app.RawFs); post_samps = round(post*app.RawFs);
%             len = pre_samps + post_samps + 1;
%             max_bl = -floor((pre - 0.05)*app.RawFs); min_bl = -floor(0.5*app.RawFs);
%             tchoose = (max_bl - min_bl) - len; % may not be robust
%             startInds = cell(1, length(app.SOnsetsSamps)); endInds = startInds;            
%             for ii = ch
%                 app.UpdateWindow.Value = vertcat({sprintf('Channel %d', ii)}, app.UpdateWindow.Value); pause(0.001);
%                 loc = app.RawData(:, ii);
%                 for jj = 1:length(app.SOnsetsSamps)
%                     win = (-pre_samps:post_samps) + app.SOnsetsSamps(jj);
%                     samp = app.RawData(win, ii); 
%                     idx = app.SOnsetsSamps(jj) - randi(tchoose, 1);
%                     rep = app.RawData(idx:(idx + len - 1), ii);
%                     rsl = (rep(end) - rep(1))/length(rep);
%                     sub = rep(1) + rsl*(1:length(rep))';
%                     ssl = (mean(samp(end-10:end)) - mean(samp(1:10)))/length(rep);
%                     adl = mean(samp(1:10)) + ssl*(1:length(rep))';
%                     startInds{jj}{ii} = win(1); endInds{jj}{ii} = win(end);
%                     loc(win) = rep + adl - sub;
%                 end
%                 app.ArtremData(:, ii) = loc;
%                 app.ArtremPP(ii, :) = [pre post];
%             end
%             app.ArtremEpoch(:, ch, :) = epochData(app.ArtremData(:, ch), app.SOnsetsSamps, 0.005, 0.02, app.RawFs);
%             for ii = ch
%                 loc_chck = app.ArtremChck(:, ii);
%                 loc_chck(isnan(loc_chck)) = 0; loc_chck = logical(loc_chck);
%                 app.ArtremMean(:, ii) = mean(app.ArtremEpoch(:, ii, loc_chck), 3);
%             end
%         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, foldername, subj, basedir, probes, idx)
        % save input variables
            app.SubjDir = fullfile(basedir, subj);
            app.SubjFileDir = fullfile(basedir, subj, foldername);
            app.MontageProbes = probes; app.MontageIdx = idx; app.Subj = subj; 
        % determine valid input
            app.SubjectFileLabel.Text = sprintf('Subject %s, File %s...loading', app.Subj, ...
                foldername); app.SubjectFileLabel.FontColor = 'r'; pause(0.1);
            [ret, fn] = hasValidDataFile(app.SubjFileDir);
            if ~ret
                error('No file found');
            else
                fn = fn{1};                
        % load progress report or call startup
                if ~isfile(fullfile(app.SubjFileDir, 'progress_report.mat'))
                    app.CallStartup(fn);
                    t = app.ManualInspectionTab;
                else
                    load(fullfile(app.SubjFileDir, 'progress_report.mat'), 'startup_calc', 'badchans_acc', ...
                        'noisetol_acc', 'artrem_calc', 'artrem_acc', 'process_calc', 'fitmodels_calc');                    
        % call startup
                    if ~startup_calc % if the initial startup has not been done
                        app.CallStartup(fn);
                        t = app.ManualInspectionTab;        
        % load data and setup manual inspection, noise inspection
                    else
                        app.LoadData(fn);
                        app.MI_Setup;
                        app.AR_Setup; 
                        t = app.ManualInspectionTab;
        % load additional files as appropriate
                        if badchans_acc; app.NT_Setup; t = app.NoiseToleranceTab; end
                        if noisetol_acc; app.NT_Load; t = app.ArtifactRemovalTab; end
                        if artrem_calc
                            app.AR_Load; 
                            if artrem_acc; t = app.ProcessingTab; else; t = app.ArtifactRemovalTab; end
                        else
                            app.ArtremData = nan(size(app.RawData));
                        end
                        if process_calc; app.Pr_Load; t = app.FitModelsTab; end
                        if fitmodels_calc
                            app.FM_Load;
                            if fitmodels_acc; t = app.QuantifyTab; else; t = app.FitModelsTab; end
                        end
                    end
                end                
            end            
        % make operable
            app.MontageNumChans = size(app.RawData, 2);
            app.TabGroup.Visible = true;
            app.TabGroup.SelectedTab = t;
            app.SubjectFileLabel.Text = sprintf('Subject %s, File %s', app.Subj, foldername);
            app.SubjectFileLabel.FontColor = 'k';

        end

        % Value changed function: MI_Ch1, MI_Ch10, MI_Ch11, 
        % MI_Ch12, MI_Ch13, MI_Ch14, MI_Ch15, MI_Ch2, MI_Ch3, 
        % MI_Ch4, MI_Ch5, MI_Ch6, MI_Ch7, MI_Ch8, MI_Ch9
        function MI_ChValueChanged(app, event)
            app.MI_Confirm.Enable = false; pause(0.001);
            app.ManualIns.ChangeDes;
            app.MI_Confirm.Enable = true; pause(0.001);
        end

        % Button pushed function: MI_Right
        function MI_RightButtonPushed(app, event)
            app.ManualIns.LRPush(1, app.RawDataCtr);
        end

        % Button pushed function: MI_Left
        function MI_LeftButtonPushed(app, event)
            app.ManualIns.LRPush(-1, app.RawDataCtr);
        end

        % Value changed function: MI_Switch
        function MI_SwitchValueChanged(app, event)
            app.ManualIns.PlotPg(app.RawDataCtr);
        end

        % Button pushed function: MI_Confirm
        function MI_ConfirmPushed(app, event)
            [app.MontageGoodChans, app.MontageBadChans] = app.ManualIns.GetGoodBad;
            SaveBadchans(fullfile(app.SubjFileDir, 'badchans.mat'), app.MontageBadChans, app.MontageGoodChans);
            badchans_acc = true;
            save(fullfile(app.SubjFileDir, 'progress_report.mat'), 'badchans_acc', '-append');
            app.NT_Setup;
            app.TabGroup.SelectedTab = app.NoiseToleranceTab;
        end

        % Button pushed function: NT_Confirm
        function NT_ConfirmPushed(app, event)
            SaveFilterOpts(fullfile(app.SubjFileDir, 'filter_opts.mat'), app.NT_HP.Value, app.NT_LP.Value, ...
                app.Pr_NF.Value, [app.NT_Notch60.Value app.NT_Notch120.Value app.NT_Notch180.Value]); 
            noisetol_acc = true;
            save(fullfile(app.SubjFileDir, 'progress_report.mat'), 'noisetol_acc', '-append');
            app.Pr_FillVals;
            app.TabGroup.SelectedTab = app.ArtifactRemovalTab;
        end

        % Button pushed function: AR_RunDefault
        function AR_RunDefaultPushed(app, event)
            if ~isempty(app.ArtremData)
                cont = CallDialogBox('This will overwrite any changes you have made in current artrem');
            else
                cont = true;
            end
            if cont
                app.ArtremData = nan(size(app.RawData));
                app.AR_Pre.Enable = false; app.AR_Post.Enable = false; app.AR_RunCh.Enable = false;
                app.AR_RunAllParams.Enable = false; pause(0.001);
                app.ArtremData(:, app.MontageGoodChans) = app.Artrem.RunArtRepAllCh(app.RawDataCtr, app.MontageGoodChans, 0.001, 0.008);
%                 app.ArtremPP = nan(app.MontageNumChans, 2);
%                 app.ArtremChck = true(length(app.SOnsetsSamps), app.MontageNumChans);
%                 app.artrep(app.MontageGoodChans, 0.001, 0.008);
%                 app.ResetArtrem;
            end
        end

        % Button pushed function: AR_Left
        function AR_LeftButtonPushed(app, event)
%             app.AR_Left.Enable = false; app.AR_Right.Enable = false; pause(0.001);
%             app.ArtremCurIdx = app.ArtremCurIdx - 1;
%             app.ArtremPlot();
%             app.AR_Left.Enable = true; app.AR_Right.Enable = true; pause(0.001);
            app.Artrem.LRPush(-1, app.ArtremData);
        end

        % Button pushed function: AR_Right
        function AR_RightButtonPushed(app, event)
%             app.AR_Left.Enable = false; app.AR_Right.Enable = false; pause(0.001);
%             app.ArtremCurIdx = app.ArtremCurIdx + 1;
%             app.ArtremPlot();
%             app.AR_Left.Enable = true; app.AR_Right.Enable = true; pause(0.001);
            app.Artrem.LRPush(1, app.ArtremData);
        end

        % Value changed function: AR_Ch1, AR_Ch10, AR_Ch11, 
        % AR_Ch12, AR_Ch13, AR_Ch14, AR_Ch15, AR_Ch2, AR_Ch3, 
        % AR_Ch4, AR_Ch5, AR_Ch6, AR_Ch7, AR_Ch8, AR_Ch9
        function AR_ChValueChanged(app, event)
            ret = app.Artrem.ChangeDes;
            app.AR_Confirm.Enable = ret;
        end

        % Value changed function: AR_AccCh
        function AR_OLDChValueChanged(app, event)
%             value = app.AR_AccCh.Value;
%             app.ArtremAcceptedCh(app.MontageGoodChans(app.ArtremCurIdx)) = value;
%             if value 
%                 if app.ArtremCurIdx ~= length(app.MontageGoodChans)
%                     app.AR_RightButtonPushed();
%                 end
%             end
%             if sum(app.ArtremAcceptedCh) == length(app.MontageGoodChans)
%                 app.AR_NumChAcc.Text = 'ALL good channels accepted';
%                 app.AR_Confirm.Enable = true;
%             else
%                 app.AR_NumChAcc.Text = sprintf('%d / %d good channels accepted', ...
%                     sum(app.ArtremAcceptedCh), length(app.MontageGoodChans));
%                 app.AR_Confirm.Enable = false;
%             end
        end

        % Button pushed function: AR_RunCh
        function AR_RunChPushed(app, event)
            ch = app.Artrem.GetCurrentChannel;
            app.AR_Pre.Enable = false; app.AR_Post.Enable = false; app.AR_RunCh.Enable = false;
            app.AR_RunAllParams.Enable = false; pause(0.001);
            app.ArtremData(:, ch) = app.Artrem.RunArtRepOneCh(app.RawDataCtr, ch, app.AR_Pre.Value, app.AR_Post.Value);
%             app.AR_AccCh.Value = false;
%             if ~isempty(app.ArtremHasBeenChanged)
%                 app.ArtremHasBeenChanged(app.ArtremCurIdx) = true;
%             end
%             app.AR_RunCh.Enable = false; pause(0.1);
%             app.artrep(app.ArtremCurIdx, app.AR_Pre.Value, app.AR_Post.Value);
%             app.ArtremPlot();
%             app.ArtremAcceptedCh(app.ArtremCurIdx) = false;
        end

        % Button pushed function: AR_RunAllParams
        function AR_RunAllParamsPushed(app, event)
            %% TODO
            if ~isempty(app.ArtremData)
                cont = CallDialogBox('This will overwrite any changes you have made in current artrem');
            else
                cont = true;
            end
            if cont
                app.ArtremData = nan(size(app.RawData));
                app.AR_Pre.Enable = false; app.AR_Post.Enable = false; app.AR_RunCh.Enable = false;
                app.AR_RunAllParams.Enable = false; pause(0.001);
                app.ArtremData(:, app.MontageGoodChans) = app.Artrem.RunArtRepAllCh(app.RawDataCtr, ...
                    app.MontageGoodChans, app.AR_Pre.Value, app.AR_Post.Value);
%                 app.ArtremData = nan(size(app.RawData));
%                 app.ArtremPP = nan(app.MontageNumChans, 2);
%                 app.artrep(app.MontageGoodChans, app.AR_Pre.Value, app.AR_Post.Value);
%                 app.ResetArtrem;
            end
        end

        % Button pushed function: AR_Confirm
        function AR_ConfirmPushed(app, event)
            %% TODO
            app.AR_Confirm.Enable = false;
            app.ArtremPP = app.Artrem.GetPP;
%             app.AR_CallSaveParams;
            SaveArtremData(fullfile(app.SubjFileDir, 'artrem_data.mat'), app.ArtremData, app.ArtremPP);
            artrem_calc = true; artrem_acc = true;
            save(fullfile(app.SubjFileDir, 'progress_report.mat'), 'artrem_calc', 'artrem_acc', '-append');
            app.TabGroup.SelectedTab = app.ProcessingTab;
            app.Pr_FilterEx.Enable = true;
            app.AR_Confirm.Enable = true;
        end

        % Value changed function: Pr_HP, Pr_LP, Pr_Notch120, 
        % Pr_Notch180, Pr_Notch60
        function Pr_FiltValueChanged(app, event)
            app.Pr_SetNF;
        end

        % Value changed function: Pr_NF
        function Pr_NFValueChanged(app, event)
            if app.Pr_NF.Value
                app.Pr_HP.Value = false; app.Pr_Notch60.Value = false; app.Pr_Notch120.Value = false;
                app.PrNotch_180.Value = false;
            end
        end

        % Button pushed function: Pr_FilterEx
        function Pr_FilterExPushed(app, event)
            app.ProcessedData = app.ArtremData;
            %app.Pr_FilterEx.Enable = false; pause(0.1);
            filt_bools = [app.Pr_HP.Value app.Pr_Notch60.Value app.Pr_Notch120.Value app.Pr_Notch180.Value app.Pr_LP.Value];
            filt_txt = {'HIGHPASS', 'NOTCH 60', 'NOTCH 120', 'NOTCH 180', 'LOWPASS'};
            filt_cut = {0.1 [57 63] [117 123] [177 183] 1000};
            filt_type = {'high', 'stop', 'stop', 'stop', 'low'};
            for ff = 1:length(filt_bools)
                if filt_bools(ff)
                    app.UpdateWindow.Value = vertcat(filt_txt(ff), app.UpdateWindow.Value); pause(0.001);
                    [z, p, k] = butter(8, filt_cut{ff}/(app.RawFs), filt_type{ff});
                    [sos, g] = zp2sos(z, p, k);
                    app.Pr_Filter(sos, g);
                end
            end
            app.UpdateWindow.Value = vertcat({'Filtering complete!'}, app.UpdateWindow.Value);
            app.Pr_FilterEx.Enable = true;
            app.Pr_EpochEx.Enable = true; app.Pr_EpochPre.Enable = true; app.Pr_EpochPost.Enable = true;pause(0.001);
        end

        % Button pushed function: Pr_EpochEx
        function Pr_EpochExButtonPushed(app, event)
%             app.Pr_EpochEx.Enable = false; pause(0.1);
            app.UpdateWindow.Value = vertcat({'EPOCHING'}, app.UpdateWindow.Value);
            [app.ProcessedData, app.TEpoch] = epochData(app.ProcessedData, app.SOnsetsSamps, ...
                abs(app.Pr_EpochPre.Value), app.Pr_EpochPost.Value, app.RawFs);
            app.UpdateWindow.Value = vertcat({'Epoching complete!'}, app.UpdateWindow.Value);
            app.Pr_EpochEx.Enable = true; app.Pr_BaselineEx.Enable = true;
        end

        % Button pushed function: Pr_BaselineEx
        function Pr_BaselineExButtonPushed(app, event)
            app.Pr_BaselineEx.Enable = false; pause(0.1);
            app.UpdateWindow.Value = vertcat({'CORRECTING TO BASELINE'}, app.UpdateWindow.Value);
            app.FiltBLlims = [app.Pr_EpochPre.Value app.Pr_EpochPost.Value];
            app.ProcessedData = baselineSubtract(app.ProcessedData, app.TEpoch, app.Pr_EpochPre.Value, app.Pr_EpochPost.Value);
            app.UpdateWindow.Value = vertcat({'Baseline subtraction and normalization complete!'}, app.UpdateWindow.Value);
            app.Pr_BaselineEx.Enable = true; app.Pr_RRex.Enable = true;
            app.Pr_CARmean.Enable = true; app.Pr_CARmed.Enable = true; app.Pr_BP.Enable = true; app.Pr_NoRR.Enable = true;
        end

        % Button pushed function: Pr_RRex
        function Pr_RRexButtonPushed(app, event)
            app.UpdateWindow.Value = vertcat({'RE-REFERENCING'}, app.UpdateWindow.Value);
            app.Pr_RRex.Enable = false; pause(0.1);
            if app.Pr_CARmean.Value
                app.FiltRRscheme = 'mean'; rr = true;
            elseif app.Pr_CARmed.Value
                app.FiltRRscheme = 'median'; rr = true;
            elseif app.Pr_BP.Value
                app.FiltRRscheme = 'bipolar'; rr = true;
            else
                app.FiltRRscheme = 'none'; rr = false;
            end
            if rr
                app.ProcessedData = rereference(app.ProcessedData, app.MontageIdx, app.MontageBadChans, app.FiltRRscheme);
            end
            app.UpdateWindow.Value = vertcat({'Re-referencing complete!'}, app.UpdateWindow.Value);
            app.Pr_RRex.Enable = true; app.Pr_PrimarySave.Enable = true;
            app.Pr_SecondarySave.Enable = true;
        end

        % Button pushed function: Pr_PrimarySave
        function Pr_PrimarySaveButtonPushed(app, event)
            app.UpdateWindow.Value = vertcat({'SAVING AS PRIMARY'}, app.UpdateWindow.Value);
            app.Pr_PrimarySave.Enable = false; pause(0.1);
            app.Pr_CallSave(fullfile(app.SubjFileDir, 'data_filt.mat'));
            app.UpdateWindow.Value = vertcat({'Data saved!'}, app.UpdateWindow.Value);
            app.Pr_PrimarySave.Enable = true; app.TabGroup.SelectedTab = app.FitModelsTab;
        end

        % Button pushed function: Pr_SecondarySave
        function Pr_SecondarySaveButtonPushed(app, event)

            app.UpdateWindow.Value = vertcat({'SAVING AS SECONDARY'}, app.UpdateWindow.Value);
            app.Pr_SecondarySave.Enable = false; pause(0.1);
            fspec = fullfile(app.SubjFileDir, '*.mat');
            [file, path] = uiputfile(fspec, 'Choose a location and filename for secondary save');
            app.Pr_CallSave(fullfile(path, file));
            app.UpdateWindow.Value = vertcat({'Data saved!'}, app.UpdateWindow.Value);
            app.Pr_SecondarySave.Enable = true;
        end

        % Button pushed function: FM_UploadCSV
        function FM_UploadCSVButtonPushed(app, event)
            [filename, pathname] = uigetfile(fullfile(app.SubjFileDir, '*.csv'));
            if ~isempty(app.ModelCMin)
                cont = CallDialogBox('This will overwrite any changes you have made in current models.');
            else
                cont = true;
            end
            if cont
                t = readtable(fullfile(pathname, filename));
                app.ModelCMin = nan(app.MontageNumChans, 4); app.ModelCMax = app.ModelCMin;
                app.ModelCStart = app.ModelCMin;
                app.ModelCMin = table2array(t(:, {'C1min', 'C2min', 'C3min', 'C4min'})); 
                app.ModelCMax = table2array(t(:, {'C1max', 'C2max', 'C3max', 'C4max'})); 
                app.ModelCStart = table2array(t(:, {'C1start', 'C2start', 'C3start', 'C4start'})); 
                app.ModelPol = logical(table2array(t(:, 'pol'))); 
                app.ModelNComp = table2array(t(:, 'ncomp'));
                app.ModelEPCh = find(~(isnan(app.ModelNComp) | app.ModelNComp == 0))';
                app.FM_ProcessCSV.Enable = true;
            end
        end

        % Button pushed function: FM_ProcessCSV
        function FM_ProcessCSVButtonPushed(app, event)
            app.FM_Init(false);
        end

        % Button pushed function: FM_ProcessDefault
        function FM_ProcessDefaultPushed(app, event)
            app.EPmean = mean(app.ProcessedData, 3);
            app.EPmedian = median(app.ProcessedData, 3);
            app.EPstd = std(app.ProcessedData, [], 3);
            app.ModelCMin = nan(app.MontageNumChans, 4); app.ModelCMax = app.ModelCMin; app.ModelCStart = app.ModelCMin;
            app.ModelCMin(app.ModelEPCh, :) = repmat([0.015 0.015 0.05  0.05], length(app.ModelEPCh), 1);
            app.ModelCMax(app.ModelEPCh, :) = repmat([0.5 0.5 0.5 0.5], length(app.ModelEPCh), 1);
            app.ModelCStart(app.ModelEPCh, :) = repmat([0.05 0.05 0.05 0.05], length(app.ModelEPCh), 1);
            app.ModelPol = []; app.ModelNComp = [];
            app.FM_Init(true);
        end

        % Button pushed function: FM_Right
        function FM_RightButtonPushed(app, event)
            app.FM_Left.Enable = false; app.FM_Right.Enable = false; pause(0.001);
            app.ModelCurIdx = app.ModelCurIdx + 1;
            app.FM_Prev.Value = false;
            app.FM_Plot();
        end

        % Button pushed function: FM_Left
        function FM_LeftButtonPushed(app, event)
            app.FM_Left.Enable = false; app.FM_Right.Enable = false; pause(0.001);
            app.ModelCurIdx = app.ModelCurIdx - 1;
            app.FM_Prev.Value = false;
            app.FM_Plot();
        end

        % Button pushed function: FM_RunCh
        function FM_RunChButtonPushed(app, event)
            cla(app.FM_Ax); % app.FM_RunCh.Enable = false; pause(0.1);
            ch = app.ModelEPCh(app.ModelCurIdx);
            app.ModelAcceptedCh(ch) = false;
            app.FM_AccCh.Value = false;
            if ~isempty(app.ModelHasBeenChanged)
                app.FM_RevertCh.Enable = true;
                app.ModelHasBeenChanged(app.ModelCurIdx) = true;
            end
            app.ModelCMin(ch, :) = [app.FM_C1min.Value app.FM_C2min.Value app.FM_C3min.Value app.FM_C4min.Value];
            app.ModelCMax(ch, :) = [app.FM_C1max.Value app.FM_C2max.Value app.FM_C3max.Value app.FM_C4max.Value];
            app.ModelCStart(ch, :) = [app.FM_C1start.Value app.FM_C2start.Value app.FM_C3start.Value app.FM_C4start.Value];
            app.ModelNComp(ch) = app.FM_NComp.Value; pol = app.FM_Pol.Value;
            if strcmp(pol, '-')
                app.ModelPol(ch) = false;
            else
                app.ModelPol(ch) = true;
            end
            app.FM_RunChan(ch);
            app.FM_Plot();
            app.FM_RevertCh.Enable = true;
        end

        % Button pushed function: FM_RevertCh
        function FM_RevertChButtonPushed(app, event)
            app.ModelHasBeenChanged(app.ModelCurIdx) = false;
            app.FM_AccCh.Value = false;
            cla(app.FM_Ax); app.FM_RevertCh.Enable = false; pause(0.1);
            ch = app.ModelEPCh(app.ModelCurIdx);
            app.ModelData(:, ch) = app.ModelInit.Data(:, ch);
            app.ModelEqn{ch} = app.ModelInit.Eqn{ch};
            app.ModelR2(ch) = app.ModelInit.R2(ch);
            app.ModelCMin(ch, :) = app.ModelInit.CMin(ch, :);
            app.ModelCMax(ch, :) = app.ModelInit.CMax(ch, :);
            app.ModelCStart(ch, :) = app.ModelInit.CStart(ch, :);
            app.ModelNComp(ch) = app.ModelInit.NComp(ch);
            app.ModelPol(ch) = app.ModelInit.Pol(ch);
            app.ModelAcceptedCh(ch) = false;
            app.FM_Plot();
            app.FM_Prev.Value = false;
            app.FM_RevertCh.Enable = true;  
        end

        % Value changed function: FM_AccCh
        function FM_AccChValueChanged(app, event)
            value = app.FM_AccCh.Value;
            app.ModelAcceptedCh(app.ModelEPCh(app.ModelCurIdx)) = value;
            if value && app.ModelCurIdx ~= length(app.ModelEPCh)
                app.FM_RightButtonPushed();
            end
            if sum(app.ModelAcceptedCh) == length(app.ModelEPCh)
                app.FM_NumChAcc.Text = 'ALL EP channels accepted';
                app.FM_Confirm.Enable = true;
            else
                app.FM_NumChAcc.Text = sprintf('%d / %d EP channels accepted', ...
                    sum(app.ModelAcceptedCh), length(app.ModelEPCh));
                app.FM_Confirm.Enable = false;
            end
            
        end

        % Value changed function: FM_Prev
        function FM_PrevValueChanged(app, event)
            value = app.FM_Prev.Value;
            if value
                n = app.ModelEPCh(app.ModelCurIdx - 1);
            else
                n = app.ModelEPCh(app.ModelCurIdx);
            end
            app.FM_C1min.Value = app.ModelCMin(n, 1);
            app.FM_C2min.Value = app.ModelCMin(n, 2);
            app.FM_C3min.Value = app.ModelCMin(n, 3);
            app.FM_C4min.Value = app.ModelCMin(n, 4);
            app.FM_C1max.Value = app.ModelCMax(n, 1);
            app.FM_C2max.Value = app.ModelCMax(n, 2);
            app.FM_C3max.Value = app.ModelCMax(n, 3);
            app.FM_C4max.Value = app.ModelCMax(n, 4);
            app.FM_C1start.Value = app.ModelCStart(n, 1);
            app.FM_C2start.Value = app.ModelCStart(n, 2);
            app.FM_C3start.Value = app.ModelCStart(n, 3);
            app.FM_C4start.Value = app.ModelCStart(n, 4);
            if app.ModelPol(n)
                app.FM_Pol.Value = '+';
            else
                app.FM_Pol.Value = '-';
            end
            app.FM_NComp.Value = app.ModelNComp(n);
        end

        % Value changed function: FM_C1max, FM_C1min, FM_C1start, 
        % FM_C2max, FM_C2min, FM_C2start, FM_C3max, FM_C3min, 
        % FM_C3start, FM_C4max, FM_C4min, FM_C4start, FM_NComp, 
        % FM_Pol
        function FM_ValuesChanged(app, event)
            app.FM_Prev.Value = false;
        end

        % Button pushed function: FM_SaveCur
        function FM_SaveCurButtonPushed(app, event)
            app.FM_CallSaveParams;
            fitmodels_calc = true;
            save(fullfile(app.SubjFileDir, 'progress_report.mat'), 'fitmodels_calc', '-append');
        end

        % Button pushed function: FM_Confirm
        function FM_ConfirmButtonPushed(app, event)
            app.FM_CallSaveParams;
            SaveModelData(fullfile(app.SubjFileDir, 'model_data.mat'), app.ModelTVals, app.ModelData, app.ModelR2);
            fitmodels_calc = true; fitmodels_acc = true;
            save(fullfile(app.SubjFileDir, 'progress_report.mat'), 'fitmodels_calc', 'fitmodels_acc', '-append');
            app.TabGroup.SelectedTab = app.QuantifyTab;
        end

        % Button pushed function: Q_QuantSave
        function Q_QuantSaveButtonPushed(app, event)
            fn = fullfile(app.SubjFileDir, app.Q_Fn.Value);
            app.ArtremChck = true(length(app.SOnsetsSamps), app.MontageNumChans);
            QuantifyAll(fn, app.MontageNumChans, app.TEpoch, app.EPmean, app.EPmedian, ...
                app.ModelEPCh, app.ModelEqn, app.ArtremChck, app.UpdateWindow, app.ProcessedData);
        end

        % Selection changed function: AR_Radio
        function AR_RadioSelectionChanged(app, event)
            selectedButton = app.AR_Radio.SelectedObject;
            n = sscanf(selectedButton.Text, 'Ch. %d');
            app.Artrem.ChangeSel(n);
            app.AR_Pre.Enable = true; app.AR_Post.Enable = true;
        end

        % Value changed function: AR_Post, AR_Pre
        function AR_PreValueChanged(app, event)
            pre = app.AR_Pre.Value; post = app.AR_Post.Value;
            [chRun, allRun] = app.Artrem.ChangePrePost([pre post]);
            app.AR_RunCh.Enable = chRun; app.AR_RunAllParams.Enable = allRun;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout3 = uigridlayout(app.UIFigure);
            app.UPDATEWINDOWPanel = uipanel(app.GridLayout3);
            app.UPDATEWINDOWPanel.Title = 'UPDATE WINDOW';
            app.GridLayout2 = uigridlayout(app.UPDATEWINDOWPanel);
            app.UpdateWindow = uitextarea(app.GridLayout2);
            app.UpdateWindow.Value = {'-- updates will be printed here --'};
            app.SubjectFileLabel = uilabel(app.GridLayout3);
            app.SubjectFileLabel.Text = 'Subject, File';
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.ManualInspectionTab = uitab(app.TabGroup);
            app.ManualInspectionTab.Title = 'Manual Inspection';
            app.MI_grid = uigridlayout(app.ManualInspectionTab);
            app.MI_ChAx1 = uiaxes(app.MI_grid);
            title(app.MI_ChAx1, '')
            xlabel(app.MI_ChAx1, '')
            ylabel(app.MI_ChAx1, '')
            app.MI_ChAx1.XTick = [];
            app.MI_ChAx1.YTick = [];
            app.MI_Ch1 = uibutton(app.MI_grid, 'state');
            app.MI_Ch1.ValueChangedFcn = createCallbackFcn(app, @MI_ChValueChanged, true);
            app.MI_Ch1.Text = 'Ch.';
            app.MI_ChAx2 = uiaxes(app.MI_grid);
            title(app.MI_ChAx2, '')
            xlabel(app.MI_ChAx2, '')
            ylabel(app.MI_ChAx2, '')
            app.MI_ChAx2.XTick = [];
            app.MI_ChAx2.YTick = [];
            app.MI_Ch2 = uibutton(app.MI_grid, 'state');
            app.MI_Ch2.ValueChangedFcn = createCallbackFcn(app, @MI_ChValueChanged, true);
            app.MI_Ch2.Text = 'Ch.';
            app.MI_ChAx3 = uiaxes(app.MI_grid);
            title(app.MI_ChAx3, '')
            xlabel(app.MI_ChAx3, '')
            ylabel(app.MI_ChAx3, '')
            app.MI_ChAx3.XTick = [];
            app.MI_ChAx3.YTick = [];
            app.MI_Ch3 = uibutton(app.MI_grid, 'state');
            app.MI_Ch3.ValueChangedFcn = createCallbackFcn(app, @MI_ChValueChanged, true);
            app.MI_Ch3.Text = 'Ch.';
            app.MI_ChAx4 = uiaxes(app.MI_grid);
            title(app.MI_ChAx4, '')
            xlabel(app.MI_ChAx4, '')
            ylabel(app.MI_ChAx4, '')
            app.MI_ChAx4.XTick = [];
            app.MI_ChAx4.YTick = [];
            app.MI_Ch4 = uibutton(app.MI_grid, 'state');
            app.MI_Ch4.ValueChangedFcn = createCallbackFcn(app, @MI_ChValueChanged, true);
            app.MI_Ch4.Text = 'Ch.';
            app.MI_ChAx5 = uiaxes(app.MI_grid);
            title(app.MI_ChAx5, '')
            xlabel(app.MI_ChAx5, '')
            ylabel(app.MI_ChAx5, '')
            app.MI_ChAx5.XTick = [];
            app.MI_ChAx5.YTick = [];
            app.MI_Ch5 = uibutton(app.MI_grid, 'state');
            app.MI_Ch5.ValueChangedFcn = createCallbackFcn(app, @MI_ChValueChanged, true);
            app.MI_Ch5.Text = 'Ch.';
            app.MI_ChAx6 = uiaxes(app.MI_grid);
            title(app.MI_ChAx6, '')
            xlabel(app.MI_ChAx6, '')
            ylabel(app.MI_ChAx6, '')
            app.MI_ChAx6.XTick = [];
            app.MI_ChAx6.YTick = [];
            app.MI_Ch6 = uibutton(app.MI_grid, 'state');
            app.MI_Ch6.ValueChangedFcn = createCallbackFcn(app, @MI_ChValueChanged, true);
            app.MI_Ch6.Text = 'Ch.';
            app.MI_ChAx7 = uiaxes(app.MI_grid);
            title(app.MI_ChAx7, '')
            xlabel(app.MI_ChAx7, '')
            ylabel(app.MI_ChAx7, '')
            app.MI_ChAx7.XTick = [];
            app.MI_ChAx7.YTick = [];
            app.MI_Ch7 = uibutton(app.MI_grid, 'state');
            app.MI_Ch7.ValueChangedFcn = createCallbackFcn(app, @MI_ChValueChanged, true);
            app.MI_Ch7.Text = 'Ch.';
            app.MI_ChAx8 = uiaxes(app.MI_grid);
            title(app.MI_ChAx8, '')
            xlabel(app.MI_ChAx8, '')
            ylabel(app.MI_ChAx8, '')
            app.MI_ChAx8.XTick = [];
            app.MI_ChAx8.YTick = [];
            app.MI_Ch8 = uibutton(app.MI_grid, 'state');
            app.MI_Ch8.ValueChangedFcn = createCallbackFcn(app, @MI_ChValueChanged, true);
            app.MI_Ch8.Text = 'Ch.';
            app.MI_ChAx9 = uiaxes(app.MI_grid);
            title(app.MI_ChAx9, '')
            xlabel(app.MI_ChAx9, '')
            ylabel(app.MI_ChAx9, '')
            app.MI_ChAx9.XTick = [];
            app.MI_ChAx9.YTick = [];
            app.MI_Ch9 = uibutton(app.MI_grid, 'state');
            app.MI_Ch9.ValueChangedFcn = createCallbackFcn(app, @MI_ChValueChanged, true);
            app.MI_Ch9.Text = 'Ch.';
            app.MI_ChAx10 = uiaxes(app.MI_grid);
            title(app.MI_ChAx10, '')
            xlabel(app.MI_ChAx10, '')
            ylabel(app.MI_ChAx10, '')
            app.MI_ChAx10.XTick = [];
            app.MI_ChAx10.YTick = [];
            app.MI_Ch10 = uibutton(app.MI_grid, 'state');
            app.MI_Ch10.ValueChangedFcn = createCallbackFcn(app, @MI_ChValueChanged, true);
            app.MI_Ch10.Text = 'Ch.';
            app.MI_ChAx11 = uiaxes(app.MI_grid);
            title(app.MI_ChAx11, '')
            xlabel(app.MI_ChAx11, '')
            ylabel(app.MI_ChAx11, '')
            app.MI_ChAx11.XTick = [];
            app.MI_ChAx11.YTick = [];
            app.MI_Ch11 = uibutton(app.MI_grid, 'state');
            app.MI_Ch11.ValueChangedFcn = createCallbackFcn(app, @MI_ChValueChanged, true);
            app.MI_Ch11.Text = 'Ch.';
            app.MI_ChAx12 = uiaxes(app.MI_grid);
            title(app.MI_ChAx12, '')
            xlabel(app.MI_ChAx12, '')
            ylabel(app.MI_ChAx12, '')
            app.MI_ChAx12.XTick = [];
            app.MI_ChAx12.YTick = [];
            app.MI_Ch12 = uibutton(app.MI_grid, 'state');
            app.MI_Ch12.ValueChangedFcn = createCallbackFcn(app, @MI_ChValueChanged, true);
            app.MI_Ch12.Text = 'Ch.';
            app.MI_ChAx13 = uiaxes(app.MI_grid);
            title(app.MI_ChAx13, '')
            xlabel(app.MI_ChAx13, '')
            ylabel(app.MI_ChAx13, '')
            app.MI_ChAx13.XTick = [];
            app.MI_ChAx13.YTick = [];
            app.MI_Ch13 = uibutton(app.MI_grid, 'state');
            app.MI_Ch13.ValueChangedFcn = createCallbackFcn(app, @MI_ChValueChanged, true);
            app.MI_Ch13.Text = 'Ch.';
            app.MI_ChAx14 = uiaxes(app.MI_grid);
            title(app.MI_ChAx14, '')
            xlabel(app.MI_ChAx14, '')
            ylabel(app.MI_ChAx14, '')
            app.MI_ChAx14.XTick = [];
            app.MI_ChAx14.YTick = [];
            app.MI_Ch14 = uibutton(app.MI_grid, 'state');
            app.MI_Ch14.ValueChangedFcn = createCallbackFcn(app, @MI_ChValueChanged, true);
            app.MI_Ch14.Text = 'Ch.';
            app.MI_ChAx15 = uiaxes(app.MI_grid);
            title(app.MI_ChAx15, '')
            xlabel(app.MI_ChAx15, '')
            ylabel(app.MI_ChAx15, '')
            app.MI_ChAx15.XTick = [];
            app.MI_ChAx15.YTick = [];
            app.MI_Ch15 = uibutton(app.MI_grid, 'state');
            app.MI_Ch15.ValueChangedFcn = createCallbackFcn(app, @MI_ChValueChanged, true);
            app.MI_Ch15.Text = 'Ch.';
            app.MI_BadCh = uilabel(app.MI_grid);
            app.MI_BadCh.Text = 'Bad Channels: ';
            app.MI_Left = uibutton(app.MI_grid, 'push');
            app.MI_Left.ButtonPushedFcn = createCallbackFcn(app, @MI_LeftButtonPushed, true);
            app.MI_Left.Text = '<';
            app.MI_Switch = uiswitch(app.MI_grid, 'slider');
            app.MI_Switch.Items = {'Full Signal', 'Baseline Only'};
            app.MI_Switch.ValueChangedFcn = createCallbackFcn(app, @MI_SwitchValueChanged, true);
            app.MI_Switch.Value = 'Baseline Only';
            app.MI_Right = uibutton(app.MI_grid, 'push');
            app.MI_Right.ButtonPushedFcn = createCallbackFcn(app, @MI_RightButtonPushed, true);
            app.MI_Right.Text = '>';
            app.MI_Confirm = uibutton(app.MI_grid, 'push');
            app.MI_Confirm.ButtonPushedFcn = createCallbackFcn(app, @MI_ConfirmPushed, true);
            app.MI_Confirm.Text = 'CONFIRM BAD CHANS AND CONTINUE';
            app.NoiseToleranceTab = uitab(app.TabGroup);
            app.NoiseToleranceTab.Title = 'Noise Tolerance';
            app.NT_grid = uigridlayout(app.NoiseToleranceTab);
            app.NT_LF_Panel = uipanel(app.NT_grid);
            app.NT_LF_Panel.Title = 'Low Frequency Noise';
            app.NT_LFgrid = uigridlayout(app.NT_LF_Panel);
            app.NT_HP = uicheckbox(app.NT_LFgrid);
            app.NT_HP.Text = 'Highpass';
            app.NT_HP.Value = true;
            app.withsignificantinterceptGaugeLabel = uilabel(app.NT_LFgrid);
            app.withsignificantinterceptGaugeLabel.Text = '% with significant intercept';
            app.NT_InterceptGauge = uigauge(app.NT_LFgrid, 'linear');
            app.NT_InterceptGauge.MajorTicks = [0 20 40 60 80 100];
            app.NT_InterceptGauge.MajorTickLabels = {''};
            app.withsignificantslopeLabel = uilabel(app.NT_LFgrid);
            app.withsignificantslopeLabel.Text = '% with significant slope';
            app.NT_SlopeGauge = uigauge(app.NT_LFgrid, 'linear');
            app.NT_SlopeGauge.MajorTicks = [0 20 40 60 80 100];
            app.NT_SlopeGauge.MajorTickLabels = {''};
            app.NT_LineNoise_Panel = uipanel(app.NT_grid);
            app.NT_LineNoise_Panel.Title = 'Line Noise';
            app.NT_LineGrid = uigridlayout(app.NT_LineNoise_Panel);
            app.NT_AxLineNoise = uiaxes(app.NT_LineGrid);
            title(app.NT_AxLineNoise, '')
            xlabel(app.NT_AxLineNoise, 'frequency')
            ylabel(app.NT_AxLineNoise, 'dB/Hz')
            app.NT_AxLineNoise.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.NT_Notch60 = uicheckbox(app.NT_LineGrid);
            app.NT_Notch60.Text = 'Notch 60';
            app.NT_Notch60.Value = true;
            app.NT_Notch120 = uicheckbox(app.NT_LineGrid);
            app.NT_Notch120.Text = 'Notch 120';
            app.NT_Notch120.Value = true;
            app.NT_Notch180 = uicheckbox(app.NT_LineGrid);
            app.NT_Notch180.Text = 'Notch 180';
            app.NT_Notch180.Value = true;
            app.NT_HF_Panel = uipanel(app.NT_grid);
            app.NT_HF_Panel.Title = 'High Frequency Noise';
            app.NT_HFgrid = uigridlayout(app.NT_HF_Panel);
            app.NT_LP = uicheckbox(app.NT_HFgrid);
            app.NT_LP.Text = 'Lowpass 1000';
            app.NT_LP.Value = true;
            app.NT_AxHFrms = uiaxes(app.NT_HFgrid);
            title(app.NT_AxHFrms, '')
            xlabel(app.NT_AxHFrms, '')
            ylabel(app.NT_AxHFrms, '')
            app.NT_AxHFsum = uiaxes(app.NT_HFgrid);
            title(app.NT_AxHFsum, '')
            xlabel(app.NT_AxHFsum, '')
            ylabel(app.NT_AxHFsum, '')
            app.NT_AxHF = uiaxes(app.NT_HFgrid);
            title(app.NT_AxHF, '')
            xlabel(app.NT_AxHF, '')
            ylabel(app.NT_AxHF, '')
            app.NT_Confirm = uibutton(app.NT_grid, 'push');
            app.NT_Confirm.ButtonPushedFcn = createCallbackFcn(app, @NT_ConfirmPushed, true);
            app.NT_Confirm.Text = 'CONFIRM FILTER CHOICES AND CONTINUE';
            app.ArtifactRemovalTab = uitab(app.TabGroup);
            app.ArtifactRemovalTab.Title = 'Artifact Removal';
            app.AR_grid = uigridlayout(app.ArtifactRemovalTab);
            app.AR_ChAx1 = uiaxes(app.AR_grid);
            title(app.AR_ChAx1, '')
            xlabel(app.AR_ChAx1, '')
            ylabel(app.AR_ChAx1, '')
            app.AR_ChAx1.XTick = [];
            app.AR_ChAx1.YTick = [];
            app.AR_ChAx1.Visible = 'off';
            app.AR_Ch1 = uibutton(app.AR_grid, 'state');
            app.AR_Ch1.ValueChangedFcn = createCallbackFcn(app, @AR_ChValueChanged, true);
            app.AR_Ch1.Enable = 'off';
            app.AR_Ch1.Text = 'Ch.';
            app.AR_ChAx2 = uiaxes(app.AR_grid);
            title(app.AR_ChAx2, '')
            xlabel(app.AR_ChAx2, '')
            ylabel(app.AR_ChAx2, '')
            app.AR_ChAx2.XTick = [];
            app.AR_ChAx2.YTick = [];
            app.AR_ChAx2.Visible = 'off';
            app.AR_Ch2 = uibutton(app.AR_grid, 'state');
            app.AR_Ch2.ValueChangedFcn = createCallbackFcn(app, @AR_ChValueChanged, true);
            app.AR_Ch2.Enable = 'off';
            app.AR_Ch2.Text = 'Ch.';
            app.AR_ChAx3 = uiaxes(app.AR_grid);
            title(app.AR_ChAx3, '')
            xlabel(app.AR_ChAx3, '')
            ylabel(app.AR_ChAx3, '')
            app.AR_ChAx3.XTick = [];
            app.AR_ChAx3.YTick = [];
            app.AR_ChAx3.Visible = 'off';
            app.AR_Ch3 = uibutton(app.AR_grid, 'state');
            app.AR_Ch3.ValueChangedFcn = createCallbackFcn(app, @AR_ChValueChanged, true);
            app.AR_Ch3.Enable = 'off';
            app.AR_Ch3.Text = 'Ch.';
            app.AR_ChAx4 = uiaxes(app.AR_grid);
            title(app.AR_ChAx4, '')
            xlabel(app.AR_ChAx4, '')
            ylabel(app.AR_ChAx4, '')
            app.AR_ChAx4.XTick = [];
            app.AR_ChAx4.YTick = [];
            app.AR_ChAx4.Visible = 'off';
            app.AR_Ch4 = uibutton(app.AR_grid, 'state');
            app.AR_Ch4.ValueChangedFcn = createCallbackFcn(app, @AR_ChValueChanged, true);
            app.AR_Ch4.Enable = 'off';
            app.AR_Ch4.Text = 'Ch.';
            app.AR_ChAx5 = uiaxes(app.AR_grid);
            title(app.AR_ChAx5, '')
            xlabel(app.AR_ChAx5, '')
            ylabel(app.AR_ChAx5, '')
            app.AR_ChAx5.XTick = [];
            app.AR_ChAx5.YTick = [];
            app.AR_ChAx5.Visible = 'off';
            app.AR_Ch5 = uibutton(app.AR_grid, 'state');
            app.AR_Ch5.ValueChangedFcn = createCallbackFcn(app, @AR_ChValueChanged, true);
            app.AR_Ch5.Enable = 'off';
            app.AR_Ch5.Text = 'Ch.';
            app.AR_ChAx6 = uiaxes(app.AR_grid);
            title(app.AR_ChAx6, '')
            xlabel(app.AR_ChAx6, '')
            ylabel(app.AR_ChAx6, '')
            app.AR_ChAx6.XTick = [];
            app.AR_ChAx6.YTick = [];
            app.AR_ChAx6.Visible = 'off';
            app.AR_Ch6 = uibutton(app.AR_grid, 'state');
            app.AR_Ch6.ValueChangedFcn = createCallbackFcn(app, @AR_ChValueChanged, true);
            app.AR_Ch6.Enable = 'off';
            app.AR_Ch6.Text = 'Ch.';
            app.AR_ChAx7 = uiaxes(app.AR_grid);
            title(app.AR_ChAx7, '')
            xlabel(app.AR_ChAx7, '')
            ylabel(app.AR_ChAx7, '')
            app.AR_ChAx7.XTick = [];
            app.AR_ChAx7.YTick = [];
            app.AR_ChAx7.Visible = 'off';
            app.AR_Ch7 = uibutton(app.AR_grid, 'state');
            app.AR_Ch7.ValueChangedFcn = createCallbackFcn(app, @AR_ChValueChanged, true);
            app.AR_Ch7.Enable = 'off';
            app.AR_Ch7.Text = 'Ch.';
            app.AR_ChAx8 = uiaxes(app.AR_grid);
            title(app.AR_ChAx8, '')
            xlabel(app.AR_ChAx8, '')
            ylabel(app.AR_ChAx8, '')
            app.AR_ChAx8.XTick = [];
            app.AR_ChAx8.YTick = [];
            app.AR_ChAx8.Visible = 'off';
            app.AR_Ch8 = uibutton(app.AR_grid, 'state');
            app.AR_Ch8.ValueChangedFcn = createCallbackFcn(app, @AR_ChValueChanged, true);
            app.AR_Ch8.Enable = 'off';
            app.AR_Ch8.Text = 'Ch.';
            app.AR_ChAx9 = uiaxes(app.AR_grid);
            title(app.AR_ChAx9, '')
            xlabel(app.AR_ChAx9, '')
            ylabel(app.AR_ChAx9, '')
            app.AR_ChAx9.XTick = [];
            app.AR_ChAx9.YTick = [];
            app.AR_ChAx9.Visible = 'off';
            app.AR_Ch9 = uibutton(app.AR_grid, 'state');
            app.AR_Ch9.ValueChangedFcn = createCallbackFcn(app, @AR_ChValueChanged, true);
            app.AR_Ch9.Enable = 'off';
            app.AR_Ch9.Text = 'Ch.';
            app.AR_ChAx10 = uiaxes(app.AR_grid);
            title(app.AR_ChAx10, '')
            xlabel(app.AR_ChAx10, '')
            ylabel(app.AR_ChAx10, '')
            app.AR_ChAx10.XTick = [];
            app.AR_ChAx10.YTick = [];
            app.AR_ChAx10.Visible = 'off';
            app.AR_Ch10 = uibutton(app.AR_grid, 'state');
            app.AR_Ch10.ValueChangedFcn = createCallbackFcn(app, @AR_ChValueChanged, true);
            app.AR_Ch10.Enable = 'off';
            app.AR_Ch10.Text = 'Ch.';
            app.AR_ChAx11 = uiaxes(app.AR_grid);
            title(app.AR_ChAx11, '')
            xlabel(app.AR_ChAx11, '')
            ylabel(app.AR_ChAx11, '')
            app.AR_ChAx11.XTick = [];
            app.AR_ChAx11.YTick = [];
            app.AR_ChAx11.Visible = 'off';
            app.AR_Ch11 = uibutton(app.AR_grid, 'state');
            app.AR_Ch11.ValueChangedFcn = createCallbackFcn(app, @AR_ChValueChanged, true);
            app.AR_Ch11.Enable = 'off';
            app.AR_Ch11.Text = 'Ch.';
            app.AR_ChAx12 = uiaxes(app.AR_grid);
            title(app.AR_ChAx12, '')
            xlabel(app.AR_ChAx12, '')
            ylabel(app.AR_ChAx12, '')
            app.AR_ChAx12.XTick = [];
            app.AR_ChAx12.YTick = [];
            app.AR_ChAx12.Visible = 'off';
            app.AR_Ch12 = uibutton(app.AR_grid, 'state');
            app.AR_Ch12.ValueChangedFcn = createCallbackFcn(app, @AR_ChValueChanged, true);
            app.AR_Ch12.Enable = 'off';
            app.AR_Ch12.Text = 'Ch.';
            app.AR_ChAx13 = uiaxes(app.AR_grid);
            title(app.AR_ChAx13, '')
            xlabel(app.AR_ChAx13, '')
            ylabel(app.AR_ChAx13, '')
            app.AR_ChAx13.XTick = [];
            app.AR_ChAx13.YTick = [];
            app.AR_ChAx13.Visible = 'off';
            app.AR_Ch13 = uibutton(app.AR_grid, 'state');
            app.AR_Ch13.ValueChangedFcn = createCallbackFcn(app, @AR_ChValueChanged, true);
            app.AR_Ch13.Enable = 'off';
            app.AR_Ch13.Text = 'Ch.';
            app.AR_ChAx14 = uiaxes(app.AR_grid);
            title(app.AR_ChAx14, '')
            xlabel(app.AR_ChAx14, '')
            ylabel(app.AR_ChAx14, '')
            app.AR_ChAx14.XTick = [];
            app.AR_ChAx14.YTick = [];
            app.AR_ChAx14.Visible = 'off';
            app.AR_Ch14 = uibutton(app.AR_grid, 'state');
            app.AR_Ch14.ValueChangedFcn = createCallbackFcn(app, @AR_ChValueChanged, true);
            app.AR_Ch14.Enable = 'off';
            app.AR_Ch14.Text = 'Ch.';
            app.AR_ChAx15 = uiaxes(app.AR_grid);
            title(app.AR_ChAx15, '')
            xlabel(app.AR_ChAx15, '')
            ylabel(app.AR_ChAx15, '')
            app.AR_ChAx15.XTick = [];
            app.AR_ChAx15.YTick = [];
            app.AR_ChAx15.Visible = 'off';
            app.AR_Ch15 = uibutton(app.AR_grid, 'state');
            app.AR_Ch15.ValueChangedFcn = createCallbackFcn(app, @AR_ChValueChanged, true);
            app.AR_Ch15.Enable = 'off';
            app.AR_Ch15.Text = 'Ch.';
            app.AR_NotAcc = uilabel(app.AR_grid);
            app.AR_NotAcc.Text = 'Not Accepted:';
            app.AR_Left = uibutton(app.AR_grid, 'push');
            app.AR_Left.ButtonPushedFcn = createCallbackFcn(app, @AR_LeftButtonPushed, true);
            app.AR_Left.Enable = 'off';
            app.AR_Left.Text = '<';
            app.AR_Right = uibutton(app.AR_grid, 'push');
            app.AR_Right.ButtonPushedFcn = createCallbackFcn(app, @AR_RightButtonPushed, true);
            app.AR_Right.Enable = 'off';
            app.AR_Right.Text = '>';
            app.AR_AccCh = uibutton(app.AR_grid, 'state');
            app.AR_AccCh.ValueChangedFcn = createCallbackFcn(app, @AR_OLDChValueChanged, true);
            app.AR_AccCh.Enable = 'off';
            app.AR_AccCh.Text = 'ACCEPT CHANNEL';
            app.AR_Confirm = uibutton(app.AR_grid, 'push');
            app.AR_Confirm.ButtonPushedFcn = createCallbackFcn(app, @AR_ConfirmPushed, true);
            app.AR_Confirm.Enable = 'off';
            app.AR_Confirm.Text = 'CONFIRM ARTREM AND CONTINUE';
            app.AR_ChannelParametersPanel = uipanel(app.AR_grid);
            app.AR_ChannelParametersPanel.Title = 'Channel Parameters';
            app.AR_ChGrid = uigridlayout(app.AR_ChannelParametersPanel);
            app.preEditFieldLabel = uilabel(app.AR_ChGrid);
            app.preEditFieldLabel.Enable = 'off';
            app.preEditFieldLabel.Text = 'pre';
            app.AR_Pre = uieditfield(app.AR_ChGrid, 'numeric');
            app.AR_Pre.Limits = [0 5];
            app.AR_Pre.ValueChangedFcn = createCallbackFcn(app, @AR_PreValueChanged, true);
            app.AR_Pre.Enable = 'off';
            app.postEditFieldLabel = uilabel(app.AR_ChGrid);
            app.postEditFieldLabel.Enable = 'off';
            app.postEditFieldLabel.Text = 'post';
            app.AR_Post = uieditfield(app.AR_ChGrid, 'numeric');
            app.AR_Post.Limits = [0 5];
            app.AR_Post.ValueChangedFcn = createCallbackFcn(app, @AR_PreValueChanged, true);
            app.AR_Post.Enable = 'off';
            app.AR_RunCh = uibutton(app.AR_ChGrid, 'push');
            app.AR_RunCh.ButtonPushedFcn = createCallbackFcn(app, @AR_RunChPushed, true);
            app.AR_RunCh.Enable = 'off';
            app.AR_RunCh.Text = 'Run Channel';
            app.AR_RunAllParams = uibutton(app.AR_ChGrid, 'push');
            app.AR_RunAllParams.ButtonPushedFcn = createCallbackFcn(app, @AR_RunAllParamsPushed, true);
            app.AR_RunAllParams.Enable = 'off';
            app.AR_RunAllParams.Text = 'Run All with These Params';
            app.AR_Radio = uibuttongroup(app.AR_ChGrid);
            app.AR_Radio.SelectionChangedFcn = createCallbackFcn(app, @AR_RadioSelectionChanged, true);
            app.AR_Radio.Title = 'Button Group';
            app.AR_Rad1 = uiradiobutton(app.AR_Radio);
            app.AR_Rad1.Text = 'Ch.';
            app.AR_Rad1.Value = true;
            app.AR_Rad6 = uiradiobutton(app.AR_Radio);
            app.AR_Rad6.Text = 'Ch.';
            app.AR_Rad11 = uiradiobutton(app.AR_Radio);
            app.AR_Rad11.Text = 'Ch.';
            app.AR_Rad2 = uiradiobutton(app.AR_Radio);
            app.AR_Rad2.Text = 'Ch.';
            app.AR_Rad7 = uiradiobutton(app.AR_Radio);
            app.AR_Rad7.Text = 'Ch.';
            app.AR_Rad12 = uiradiobutton(app.AR_Radio);
            app.AR_Rad12.Text = 'Ch.';
            app.AR_Rad3 = uiradiobutton(app.AR_Radio);
            app.AR_Rad3.Text = 'Ch.';
            app.AR_Rad8 = uiradiobutton(app.AR_Radio);
            app.AR_Rad8.Text = 'Ch.';
            app.AR_Rad13 = uiradiobutton(app.AR_Radio);
            app.AR_Rad13.Text = 'Ch.';
            app.AR_Rad4 = uiradiobutton(app.AR_Radio);
            app.AR_Rad4.Text = 'Ch.';
            app.AR_Rad9 = uiradiobutton(app.AR_Radio);
            app.AR_Rad9.Text = 'Ch.';
            app.AR_Rad14 = uiradiobutton(app.AR_Radio);
            app.AR_Rad14.Text = 'Ch.';
            app.AR_Rad5 = uiradiobutton(app.AR_Radio);
            app.AR_Rad5.Text = 'Ch.';
            app.AR_Rad10 = uiradiobutton(app.AR_Radio);
            app.AR_Rad10.Text = 'Ch.';
            app.AR_Rad15 = uiradiobutton(app.AR_Radio);
            app.AR_Rad15.Text = 'Ch.';
            app.AR_RunDefault = uibutton(app.AR_grid, 'push');
            app.AR_RunDefault.ButtonPushedFcn = createCallbackFcn(app, @AR_RunDefaultPushed, true);
            app.AR_RunDefault.Text = 'Run all with Defaults';
            app.ProcessingTab = uitab(app.TabGroup);
            app.ProcessingTab.Title = 'Processing';
            app.Pr_Grid = uigridlayout(app.ProcessingTab);
            app.Pr_FiltersPanel = uipanel(app.Pr_Grid);
            app.Pr_FiltersPanel.Title = 'Filters';
            app.Pr_FiltersGrid = uigridlayout(app.Pr_FiltersPanel);
            app.Pr_HP = uicheckbox(app.Pr_FiltersGrid);
            app.Pr_HP.ValueChangedFcn = createCallbackFcn(app, @Pr_FiltValueChanged, true);
            app.Pr_HP.Text = 'Highpass 0.1';
            app.Pr_Notch60 = uicheckbox(app.Pr_FiltersGrid);
            app.Pr_Notch60.ValueChangedFcn = createCallbackFcn(app, @Pr_FiltValueChanged, true);
            app.Pr_Notch60.Text = 'Notch 60';
            app.Pr_Notch120 = uicheckbox(app.Pr_FiltersGrid);
            app.Pr_Notch120.ValueChangedFcn = createCallbackFcn(app, @Pr_FiltValueChanged, true);
            app.Pr_Notch120.Text = 'Notch 120';
            app.Pr_Notch180 = uicheckbox(app.Pr_FiltersGrid);
            app.Pr_Notch180.ValueChangedFcn = createCallbackFcn(app, @Pr_FiltValueChanged, true);
            app.Pr_Notch180.Text = 'Notch 180';
            app.Pr_LP = uicheckbox(app.Pr_FiltersGrid);
            app.Pr_LP.ValueChangedFcn = createCallbackFcn(app, @Pr_FiltValueChanged, true);
            app.Pr_LP.Text = 'Lowpass 1000';
            app.Pr_FilterEx = uibutton(app.Pr_FiltersGrid, 'push');
            app.Pr_FilterEx.ButtonPushedFcn = createCallbackFcn(app, @Pr_FilterExPushed, true);
            app.Pr_FilterEx.Text = 'FILTER';
            app.Pr_NF = uicheckbox(app.Pr_FiltersGrid);
            app.Pr_NF.ValueChangedFcn = createCallbackFcn(app, @Pr_NFValueChanged, true);
            app.Pr_NF.Text = 'No filters';
            app.BaselinePr_BaselinePanel = uipanel(app.Pr_Grid);
            app.BaselinePr_BaselinePanel.Title = 'Baseline Subtraction/Normalization';
            app.Pr_BaselineGrid = uigridlayout(app.BaselinePr_BaselinePanel);
            app.Pr_BaselineEx = uibutton(app.Pr_BaselineGrid, 'push');
            app.Pr_BaselineEx.ButtonPushedFcn = createCallbackFcn(app, @Pr_BaselineExButtonPushed, true);
            app.Pr_BaselineEx.Text = 'SUBTRACT AND NORMALIZE';
            app.BaselineEditFieldLabel = uilabel(app.Pr_BaselineGrid);
            app.BaselineEditFieldLabel.Text = 'Baseline';
            app.Pr_BaselineMin = uieditfield(app.Pr_BaselineGrid, 'numeric');
            app.Pr_BaselineMin.Value = -0.4;
            app.toEditFieldLabel = uilabel(app.Pr_BaselineGrid);
            app.toEditFieldLabel.Text = 'to';
            app.Pr_BaselineMax = uieditfield(app.Pr_BaselineGrid, 'numeric');
            app.Pr_BaselineMax.Value = -0.1;
            app.Pr_EpochPanel = uipanel(app.Pr_Grid);
            app.Pr_EpochPanel.Title = 'Epoching';
            app.Pr_EpochGrid = uigridlayout(app.Pr_EpochPanel);
            app.Pr_EpochEx = uibutton(app.Pr_EpochGrid, 'push');
            app.Pr_EpochEx.ButtonPushedFcn = createCallbackFcn(app, @Pr_EpochExButtonPushed, true);
            app.Pr_EpochEx.Text = 'EPOCH';
            app.PreSliderLabel = uilabel(app.Pr_EpochGrid);
            app.PreSliderLabel.Text = 'Pre';
            app.Pr_EpochPre = uislider(app.Pr_EpochGrid);
            app.Pr_EpochPre.Limits = [-2 0];
            app.Pr_EpochPre.MajorTicks = [-2 -1.75 -1.5 -1.25 -1 -0.75 -0.5 -0.25 0];
            app.Pr_EpochPre.MinorTicks = [];
            app.Pr_EpochPre.Value = -0.5;
            app.PostSliderLabel = uilabel(app.Pr_EpochGrid);
            app.PostSliderLabel.Text = 'Post';
            app.Pr_EpochPost = uislider(app.Pr_EpochGrid);
            app.Pr_EpochPost.Limits = [0 2];
            app.Pr_EpochPost.MajorTicks = [0 0.25 0.5 0.75 1 1.25 1.5 1.75 2];
            app.Pr_EpochPost.MinorTicks = [];
            app.Pr_EpochPost.Value = 1;
            app.ReReferencePanel = uipanel(app.Pr_Grid);
            app.ReReferencePanel.Title = 'Re-Reference';
            app.Pr_RRgrid = uigridlayout(app.ReReferencePanel);
            app.Pr_RRscheme = uibuttongroup(app.Pr_RRgrid);
            app.Pr_RRscheme.Title = 'Scheme';
            app.Pr_CARmean = uitogglebutton(app.Pr_RRscheme);
            app.Pr_CARmean.Text = 'Common Mean';
            app.Pr_CARmed = uitogglebutton(app.Pr_RRscheme);
            app.Pr_CARmed.Text = 'Common Median';
            app.Pr_BP = uitogglebutton(app.Pr_RRscheme);
            app.Pr_BP.Text = 'Bipolar';
            app.Pr_NoRR = uitogglebutton(app.Pr_RRscheme);
            app.Pr_NoRR.Text = 'None';
            app.Pr_NoRR.Value = true;
            app.Pr_RRex = uibutton(app.Pr_RRgrid, 'push');
            app.Pr_RRex.ButtonPushedFcn = createCallbackFcn(app, @Pr_RRexButtonPushed, true);
            app.Pr_RRex.Text = 'REFERENCE';
            app.Pr_PrimarySave = uibutton(app.Pr_Grid, 'push');
            app.Pr_PrimarySave.ButtonPushedFcn = createCallbackFcn(app, @Pr_PrimarySaveButtonPushed, true);
            app.Pr_PrimarySave.Text = 'SAVE AS PRIMARY';
            app.Pr_SecondarySave = uibutton(app.Pr_Grid, 'push');
            app.Pr_SecondarySave.ButtonPushedFcn = createCallbackFcn(app, @Pr_SecondarySaveButtonPushed, true);
            app.Pr_SecondarySave.Text = 'save as secondary';
            app.EPIdentificationTab = uitab(app.TabGroup);
            app.EPIdentificationTab.Title = 'EP Identification';
            app.MI_grid_2 = uigridlayout(app.EPIdentificationTab);
            app.MI_ChAx1_2 = uiaxes(app.MI_grid_2);
            title(app.MI_ChAx1_2, '')
            xlabel(app.MI_ChAx1_2, '')
            ylabel(app.MI_ChAx1_2, '')
            app.MI_ChAx1_2.XTick = [];
            app.MI_ChAx1_2.YTick = [];
            app.MI_Ch1_2 = uibutton(app.MI_grid_2, 'state');
            app.MI_Ch1_2.Text = 'Ch.';
            app.MI_ChAx2_2 = uiaxes(app.MI_grid_2);
            title(app.MI_ChAx2_2, '')
            xlabel(app.MI_ChAx2_2, '')
            ylabel(app.MI_ChAx2_2, '')
            app.MI_ChAx2_2.XTick = [];
            app.MI_ChAx2_2.YTick = [];
            app.MI_Ch2_2 = uibutton(app.MI_grid_2, 'state');
            app.MI_Ch2_2.Text = 'Ch.';
            app.MI_ChAx3_2 = uiaxes(app.MI_grid_2);
            title(app.MI_ChAx3_2, '')
            xlabel(app.MI_ChAx3_2, '')
            ylabel(app.MI_ChAx3_2, '')
            app.MI_ChAx3_2.XTick = [];
            app.MI_ChAx3_2.YTick = [];
            app.MI_Ch3_2 = uibutton(app.MI_grid_2, 'state');
            app.MI_Ch3_2.Text = 'Ch.';
            app.MI_ChAx4_2 = uiaxes(app.MI_grid_2);
            title(app.MI_ChAx4_2, '')
            xlabel(app.MI_ChAx4_2, '')
            ylabel(app.MI_ChAx4_2, '')
            app.MI_ChAx4_2.XTick = [];
            app.MI_ChAx4_2.YTick = [];
            app.MI_Ch4_2 = uibutton(app.MI_grid_2, 'state');
            app.MI_Ch4_2.Text = 'Ch.';
            app.MI_ChAx5_2 = uiaxes(app.MI_grid_2);
            title(app.MI_ChAx5_2, '')
            xlabel(app.MI_ChAx5_2, '')
            ylabel(app.MI_ChAx5_2, '')
            app.MI_ChAx5_2.XTick = [];
            app.MI_ChAx5_2.YTick = [];
            app.MI_Ch5_2 = uibutton(app.MI_grid_2, 'state');
            app.MI_Ch5_2.Text = 'Ch.';
            app.MI_ChAx6_2 = uiaxes(app.MI_grid_2);
            title(app.MI_ChAx6_2, '')
            xlabel(app.MI_ChAx6_2, '')
            ylabel(app.MI_ChAx6_2, '')
            app.MI_ChAx6_2.XTick = [];
            app.MI_ChAx6_2.YTick = [];
            app.MI_Ch6_2 = uibutton(app.MI_grid_2, 'state');
            app.MI_Ch6_2.Text = 'Ch.';
            app.MI_ChAx7_2 = uiaxes(app.MI_grid_2);
            title(app.MI_ChAx7_2, '')
            xlabel(app.MI_ChAx7_2, '')
            ylabel(app.MI_ChAx7_2, '')
            app.MI_ChAx7_2.XTick = [];
            app.MI_ChAx7_2.YTick = [];
            app.MI_Ch7_2 = uibutton(app.MI_grid_2, 'state');
            app.MI_Ch7_2.Text = 'Ch.';
            app.MI_ChAx8_2 = uiaxes(app.MI_grid_2);
            title(app.MI_ChAx8_2, '')
            xlabel(app.MI_ChAx8_2, '')
            ylabel(app.MI_ChAx8_2, '')
            app.MI_ChAx8_2.XTick = [];
            app.MI_ChAx8_2.YTick = [];
            app.MI_Ch8_2 = uibutton(app.MI_grid_2, 'state');
            app.MI_Ch8_2.Text = 'Ch.';
            app.MI_ChAx9_2 = uiaxes(app.MI_grid_2);
            title(app.MI_ChAx9_2, '')
            xlabel(app.MI_ChAx9_2, '')
            ylabel(app.MI_ChAx9_2, '')
            app.MI_ChAx9_2.XTick = [];
            app.MI_ChAx9_2.YTick = [];
            app.MI_Ch9_2 = uibutton(app.MI_grid_2, 'state');
            app.MI_Ch9_2.Text = 'Ch.';
            app.MI_ChAx10_2 = uiaxes(app.MI_grid_2);
            title(app.MI_ChAx10_2, '')
            xlabel(app.MI_ChAx10_2, '')
            ylabel(app.MI_ChAx10_2, '')
            app.MI_ChAx10_2.XTick = [];
            app.MI_ChAx10_2.YTick = [];
            app.MI_Ch10_2 = uibutton(app.MI_grid_2, 'state');
            app.MI_Ch10_2.Text = 'Ch.';
            app.MI_ChAx11_2 = uiaxes(app.MI_grid_2);
            title(app.MI_ChAx11_2, '')
            xlabel(app.MI_ChAx11_2, '')
            ylabel(app.MI_ChAx11_2, '')
            app.MI_ChAx11_2.XTick = [];
            app.MI_ChAx11_2.YTick = [];
            app.MI_Ch11_2 = uibutton(app.MI_grid_2, 'state');
            app.MI_Ch11_2.Text = 'Ch.';
            app.MI_ChAx12_2 = uiaxes(app.MI_grid_2);
            title(app.MI_ChAx12_2, '')
            xlabel(app.MI_ChAx12_2, '')
            ylabel(app.MI_ChAx12_2, '')
            app.MI_ChAx12_2.XTick = [];
            app.MI_ChAx12_2.YTick = [];
            app.MI_Ch12_2 = uibutton(app.MI_grid_2, 'state');
            app.MI_Ch12_2.Text = 'Ch.';
            app.MI_ChAx13_2 = uiaxes(app.MI_grid_2);
            title(app.MI_ChAx13_2, '')
            xlabel(app.MI_ChAx13_2, '')
            ylabel(app.MI_ChAx13_2, '')
            app.MI_ChAx13_2.XTick = [];
            app.MI_ChAx13_2.YTick = [];
            app.MI_Ch13_2 = uibutton(app.MI_grid_2, 'state');
            app.MI_Ch13_2.Text = 'Ch.';
            app.MI_ChAx14_2 = uiaxes(app.MI_grid_2);
            title(app.MI_ChAx14_2, '')
            xlabel(app.MI_ChAx14_2, '')
            ylabel(app.MI_ChAx14_2, '')
            app.MI_ChAx14_2.XTick = [];
            app.MI_ChAx14_2.YTick = [];
            app.MI_Ch14_2 = uibutton(app.MI_grid_2, 'state');
            app.MI_Ch14_2.Text = 'Ch.';
            app.MI_ChAx15_2 = uiaxes(app.MI_grid_2);
            title(app.MI_ChAx15_2, '')
            xlabel(app.MI_ChAx15_2, '')
            ylabel(app.MI_ChAx15_2, '')
            app.MI_ChAx15_2.XTick = [];
            app.MI_ChAx15_2.YTick = [];
            app.MI_Ch15_2 = uibutton(app.MI_grid_2, 'state');
            app.MI_Ch15_2.Text = 'Ch.';
            app.MI_BadCh_2 = uilabel(app.MI_grid_2);
            app.MI_BadCh_2.Text = 'EP Channels: ';
            app.MI_Left_2 = uibutton(app.MI_grid_2, 'push');
            app.MI_Left_2.Text = '<';
            app.MI_Right_2 = uibutton(app.MI_grid_2, 'push');
            app.MI_Right_2.Text = '>';
            app.MI_Confirm_2 = uibutton(app.MI_grid_2, 'push');
            app.MI_Confirm_2.Text = 'CONFIRM EP CHANS AND CONTINUE';
            app.FitModelsTab = uitab(app.TabGroup);
            app.FitModelsTab.Title = 'Fit Models';
            app.FM_Grid = uigridlayout(app.FitModelsTab);
            app.FM_Ax = uiaxes(app.FM_Grid);
            title(app.FM_Ax, 'Ch. ')
            xlabel(app.FM_Ax, '')
            ylabel(app.FM_Ax, '')
            app.FM_UploadCSV = uibutton(app.FM_Grid, 'push');
            app.FM_UploadCSV.ButtonPushedFcn = createCallbackFcn(app, @FM_UploadCSVButtonPushed, true);
            app.FM_UploadCSV.Text = 'Upload Model Parameter CSV';
            app.FM_ProcessCSV = uibutton(app.FM_Grid, 'push');
            app.FM_ProcessCSV.ButtonPushedFcn = createCallbackFcn(app, @FM_ProcessCSVButtonPushed, true);
            app.FM_ProcessCSV.Enable = 'off';
            app.FM_ProcessCSV.Text = 'Fit All from CSV';
            app.FM_Left = uibutton(app.FM_Grid, 'push');
            app.FM_Left.ButtonPushedFcn = createCallbackFcn(app, @FM_LeftButtonPushed, true);
            app.FM_Left.Enable = 'off';
            app.FM_Left.Text = '<';
            app.FM_Right = uibutton(app.FM_Grid, 'push');
            app.FM_Right.ButtonPushedFcn = createCallbackFcn(app, @FM_RightButtonPushed, true);
            app.FM_Right.Enable = 'off';
            app.FM_Right.Text = '>';
            app.FM_R2 = uilabel(app.FM_Grid);
            app.FM_R2.Text = 'R2 =';
            app.FM_AccCh = uibutton(app.FM_Grid, 'state');
            app.FM_AccCh.ValueChangedFcn = createCallbackFcn(app, @FM_AccChValueChanged, true);
            app.FM_AccCh.Enable = 'off';
            app.FM_AccCh.Text = 'ACCEPT CHANNEL';
            app.FM_NumChAcc = uilabel(app.FM_Grid);
            app.FM_NumChAcc.Enable = 'off';
            app.FM_NumChAcc.Text = '/ channels accepted';
            app.FM_Confirm = uibutton(app.FM_Grid, 'push');
            app.FM_Confirm.ButtonPushedFcn = createCallbackFcn(app, @FM_ConfirmButtonPushed, true);
            app.FM_Confirm.Enable = 'off';
            app.FM_Confirm.Text = 'CONFIRM MODELS AND CONTINUE';
            app.FM_ProcessDefault = uibutton(app.FM_Grid, 'push');
            app.FM_ProcessDefault.ButtonPushedFcn = createCallbackFcn(app, @FM_ProcessDefaultPushed, true);
            app.FM_ProcessDefault.Text = 'Fit All with Defaults';
            app.FM_ChannelParametersPanel = uipanel(app.FM_Grid);
            app.FM_ChannelParametersPanel.Title = 'Channel Parameters';
            app.FM_ChGrid = uigridlayout(app.FM_ChannelParametersPanel);
            app.FM_C1 = uilabel(app.FM_ChGrid);
            app.FM_C1.Text = 'C1';
            app.FM_C1min = uieditfield(app.FM_ChGrid, 'numeric');
            app.FM_C1min.ValueChangedFcn = createCallbackFcn(app, @FM_ValuesChanged, true);
            app.FM_C1max = uieditfield(app.FM_ChGrid, 'numeric');
            app.FM_C1max.ValueChangedFcn = createCallbackFcn(app, @FM_ValuesChanged, true);
            app.FM_Min = uilabel(app.FM_ChGrid);
            app.FM_Min.Text = 'Min';
            app.FM_C2max = uieditfield(app.FM_ChGrid, 'numeric');
            app.FM_C2max.ValueChangedFcn = createCallbackFcn(app, @FM_ValuesChanged, true);
            app.FM_C2min = uieditfield(app.FM_ChGrid, 'numeric');
            app.FM_C2min.ValueChangedFcn = createCallbackFcn(app, @FM_ValuesChanged, true);
            app.FM_C2 = uilabel(app.FM_ChGrid);
            app.FM_C2.Text = 'C2';
            app.FM_C3 = uilabel(app.FM_ChGrid);
            app.FM_C3.Text = 'C3';
            app.FM_C3min = uieditfield(app.FM_ChGrid, 'numeric');
            app.FM_C3min.ValueChangedFcn = createCallbackFcn(app, @FM_ValuesChanged, true);
            app.FM_C3max = uieditfield(app.FM_ChGrid, 'numeric');
            app.FM_C3max.ValueChangedFcn = createCallbackFcn(app, @FM_ValuesChanged, true);
            app.FM_C4 = uilabel(app.FM_ChGrid);
            app.FM_C4.Text = 'C4';
            app.FM_C4min = uieditfield(app.FM_ChGrid, 'numeric');
            app.FM_C4min.ValueChangedFcn = createCallbackFcn(app, @FM_ValuesChanged, true);
            app.FM_C4max = uieditfield(app.FM_ChGrid, 'numeric');
            app.FM_C4max.ValueChangedFcn = createCallbackFcn(app, @FM_ValuesChanged, true);
            app.C1PolaritySwitchLabel = uilabel(app.FM_ChGrid);
            app.C1PolaritySwitchLabel.Text = 'C1 Polarity';
            app.FM_Pol = uiswitch(app.FM_ChGrid, 'slider');
            app.FM_Pol.Items = {'-', '+'};
            app.FM_Pol.ValueChangedFcn = createCallbackFcn(app, @FM_ValuesChanged, true);
            app.FM_Pol.Value = '-';
            app.FM_C1start = uieditfield(app.FM_ChGrid, 'numeric');
            app.FM_C1start.ValueChangedFcn = createCallbackFcn(app, @FM_ValuesChanged, true);
            app.FM_C4start = uieditfield(app.FM_ChGrid, 'numeric');
            app.FM_C4start.ValueChangedFcn = createCallbackFcn(app, @FM_ValuesChanged, true);
            app.FM_C2start = uieditfield(app.FM_ChGrid, 'numeric');
            app.FM_C2start.ValueChangedFcn = createCallbackFcn(app, @FM_ValuesChanged, true);
            app.FM_C3start = uieditfield(app.FM_ChGrid, 'numeric');
            app.FM_C3start.ValueChangedFcn = createCallbackFcn(app, @FM_ValuesChanged, true);
            app.FM_Start = uilabel(app.FM_ChGrid);
            app.FM_Start.Text = 'Start';
            app.FM_Max = uilabel(app.FM_ChGrid);
            app.FM_Max.Text = 'Max';
            app.FM_ComponentWidth = uilabel(app.FM_ChGrid);
            app.FM_ComponentWidth.Text = 'Component Width Parameters';
            app.FM_RunCh = uibutton(app.FM_ChGrid, 'push');
            app.FM_RunCh.ButtonPushedFcn = createCallbackFcn(app, @FM_RunChButtonPushed, true);
            app.FM_RunCh.Enable = 'off';
            app.FM_RunCh.Text = 'Run';
            app.FM_RevertCh = uibutton(app.FM_ChGrid, 'push');
            app.FM_RevertCh.ButtonPushedFcn = createCallbackFcn(app, @FM_RevertChButtonPushed, true);
            app.FM_RevertCh.Enable = 'off';
            app.FM_RevertCh.Text = 'Revert';
            app.Components14Label = uilabel(app.FM_ChGrid);
            app.Components14Label.Text = {'Components'; '(1-4)'};
            app.FM_NComp = uieditfield(app.FM_ChGrid, 'numeric');
            app.FM_NComp.Limits = [1 4];
            app.FM_NComp.ValueChangedFcn = createCallbackFcn(app, @FM_ValuesChanged, true);
            app.FM_NComp.Value = 4;
            app.FM_Prev = uibutton(app.FM_ChGrid, 'state');
            app.FM_Prev.ValueChangedFcn = createCallbackFcn(app, @FM_PrevValueChanged, true);
            app.FM_Prev.Enable = 'off';
            app.FM_Prev.Text = 'Use Previous Ch Settings';
            app.FM_SaveCur = uibutton(app.FM_Grid, 'push');
            app.FM_SaveCur.ButtonPushedFcn = createCallbackFcn(app, @FM_SaveCurButtonPushed, true);
            app.FM_SaveCur.Enable = 'off';
            app.FM_SaveCur.Text = 'Save Current Version';
            app.QuantifyTab = uitab(app.TabGroup);
            app.QuantifyTab.Title = 'Quantify';
            app.Q_Panel = uigridlayout(app.QuantifyTab);
            app.Q_QuantSave = uibutton(app.Q_Panel, 'push');
            app.Q_QuantSave.ButtonPushedFcn = createCallbackFcn(app, @Q_QuantSaveButtonPushed, true);
            app.Q_QuantSave.Text = 'QUANTIFY AND SAVE';
            app.filenameEditFieldLabel = uilabel(app.Q_Panel);
            app.filenameEditFieldLabel.Text = 'filename';
            app.Q_Fn = uieditfield(app.Q_Panel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CCEP_file(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end